% description: Copyright 2019 Luca Della Santina
classdef FollicleFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ManualDetectionTab             matlab.ui.container.Tab
        btnChooseUser                  matlab.ui.control.Button
        btnPhotosFolder                matlab.ui.control.Button
        btnTagFollicles                matlab.ui.control.Button
        btnTraceEyelid                 matlab.ui.control.Button
        btnDeleteRow                   matlab.ui.control.Button
        btnExportTraining              matlab.ui.control.Button
        btnImportTraining              matlab.ui.control.Button
        AutoDetectionTab               matlab.ui.container.Tab
        btnAutoTagFollicles            matlab.ui.control.Button
        btnAutoChooseUser              matlab.ui.control.Button
        btnAutoPhotosFolder            matlab.ui.control.Button
        MinsizeSpinnerLabel            matlab.ui.control.Label
        cmbMinSize                     matlab.ui.control.Spinner
        MaxsizeSpinnerLabel            matlab.ui.control.Label
        cmbMaxSize                     matlab.ui.control.Spinner
        chkWatershed                   matlab.ui.control.CheckBox
        MinSNRSpinnerLabel             matlab.ui.control.Label
        cmbMinSNR                      matlab.ui.control.Spinner
        btnValidateFollicles           matlab.ui.control.Button
        BackgroundlevelestimatorLabel  matlab.ui.control.Label
        cmbNoiseEstimator              matlab.ui.control.DropDown
        NeuralNetTab                   matlab.ui.container.Tab
        btnNetGTruth                   matlab.ui.control.Button
        btnNetTrain                    matlab.ui.control.Button
        btnNetTest                     matlab.ui.control.Button
        NetNameEditFieldLabel          matlab.ui.control.Label
        txtNetName                     matlab.ui.control.EditField
        NetTypeDropDownLabel           matlab.ui.control.Label
        cmbNetType                     matlab.ui.control.DropDown
        NetModelDropDownLabel          matlab.ui.control.Label
        cmbNetModel                    matlab.ui.control.DropDown
        btnNetCreate                   matlab.ui.control.Button
        btnNetDelete                   matlab.ui.control.Button
        btnNetClone                    matlab.ui.control.Button
        btnNetDesign                   matlab.ui.control.Button
        btnNetInfo                     matlab.ui.control.Button
        btnNetImport                   matlab.ui.control.Button
        btnNetExport                   matlab.ui.control.Button
        CompareTab                     matlab.ui.container.Tab
        btnCompareEyelid               matlab.ui.control.Button
        ToolsTab                       matlab.ui.container.Tab
        btnBatchResize                 matlab.ui.control.Button
        btnBatchMaskEyelid             matlab.ui.control.Button
        btnBatchSaveEachFollicle       matlab.ui.control.Button
        btnBatchMaskFollicles          matlab.ui.control.Button
        btnRelinkImages                matlab.ui.control.Button
        btnBatchSaveBboxes             matlab.ui.control.Button
        btnMergeRecords                matlab.ui.control.Button
        SettingsTab                    matlab.ui.container.Tab
        ZoomlevelPanel                 matlab.ui.container.Panel
        FolliclesLabel                 matlab.ui.control.Label
        cmbZoomFollicles               matlab.ui.control.Spinner
        EyelidLabel                    matlab.ui.control.Label
        cmbZoomEyelid                  matlab.ui.control.Spinner
        ObjectsColorPanel              matlab.ui.container.Panel
        btnColorSelected               matlab.ui.control.Button
        btnColorFalse                  matlab.ui.control.Button
        btnColorTrue                   matlab.ui.control.Button
        btnColorMaybe                  matlab.ui.control.Button
        ColorFalse                     matlab.ui.control.Lamp
        ColorSelected                  matlab.ui.control.Lamp
        ColorTrue                      matlab.ui.control.Lamp
        ColorMaybe                     matlab.ui.control.Lamp
        btnRefreshTable_2              matlab.ui.control.Button
        AboutTab                       matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        FollicleFinderLabel            matlab.ui.control.Label
        Version20beta1Label            matlab.ui.control.Label
        Copyright2019LucaDellaSantinaLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Panel                          matlab.ui.container.Panel
        Log                            matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        btnRefreshTable                matlab.ui.control.Button
        tblTraining                    matlab.ui.control.Table
        tblNeuralNet                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        UserName        = '';
        ImagesFolder    = '';
        RowSelected     = []; % Row selected in Training UITable
        NetSelected     = []; % Row selected in NeuralNet UITable
        FollicleTypes   = containers.Map({'False','Maybe','True'},[-1,0,1]);
        DiagnosisTypes  = containers.Map({'TNormal','TF','TI', 'TF+TI', 'TS','TT','CO'}, [1,2,3,4,5,6,7]);
    end
    
    methods (Access = private)
        
        function RefreshUserName(app)
            if ~isempty(app.UserName)
                app.UIFigure.Name = ['Follicle Finder - User: ' app.UserName];
            else
                app.UIFigure.Name = 'Follicle Finder';
            end
        end
        
        function RefreshTrainingTable(app)
            % Refreshes the list of available traning session
            app.tblTraining.Data = {};
            app.tblTraining.Data = listTraining;

            app.btnDeleteRow.Enable             = 'off';
            app.btnExportTraining.Enable        = 'off';
            app.btnBatchMaskEyelid.Enable       = 'off';
            app.btnBatchMaskFollicles.Enable    = 'off';
            app.btnBatchSaveEachFollicle.Enable = 'off';
            app.btnBatchSaveBboxes.Enable       = 'off';
            app.btnRelinkImages.Enable          = 'off';
            
            if isempty(app.ImagesFolder)
                app.btnTagFollicles.Enable  = 'off';
                app.btnTraceEyelid.Enable   = 'off';
            else
                app.btnTagFollicles.Enable  = 'on';
                app.btnTraceEyelid.Enable   = 'on';
            end
        end
        
        function RefreshNeuralNetTable(app)
            % Refreshes the list of available traning session
            app.tblNeuralNet.Data = {};
            app.tblNeuralNet.Data = listNeuralNet;

            app.btnNetDelete.Enable     = 'off';
            app.btnNetClone.Enable      = 'off';
            app.btnNetImport.Enable     = 'off';
            app.btnNetExport.Enable     = 'off';
            app.btnNetInfo.Enable       = 'off';
            app.btnNetGTruth.Enable     = 'off';
            app.btnNetTrain.Enable      = 'off';
            app.btnNetTest.Enable       = 'off';
            app.txtNetName.Value        = '';
        end

        function Prefs = getPrefsFollicles(app)
            Prefs                   = struct;
            Prefs.Type              = 'Follicles';
            Prefs.Zoom              = app.cmbZoomFollicles.Value;
            Prefs.actionType        = 'Add';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot
        end
        function Prefs = getPrefsEyelid(app)
            Prefs                   = struct;
            Prefs.Type              = 'Eyelid';
            Prefs.Zoom              = app.cmbZoomEyelid.Value;
            Prefs.actionType        = 'Enclose';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot
        end
               
        function Prefs = getPrefsAutoFollicles(app)
            Prefs = struct;
            Prefs.Type              = 'Follicles';
            Prefs.Zoom              = app.cmbZoomFollicles.Value;
            Prefs.actionType        = 'Select';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot            
            Prefs.objfinder.maxDotSize   = inf; % max dot size exclusion criteria.
            Prefs.objfinder.minDotSize   = 0;   % min dot size exclusion criteria.
            Prefs.objfinder.watershed    = app.chkWatershed.Value; % False if you want to skip watershed segmentation during object search
            Prefs.objfinder.sphericity   = true;                   % True if you want to calculare sphericity
            Prefs.objfinder.minIntensity = app.cmbMinSNR.Value;    % Minumum allowed voxel intensity (# times the local noise of the block)
            switch app.cmbNoiseEstimator.Value
                case 'Most common intensity', Prefs.objfinder.noiseEstimator = 'mode';
                case 'Standard deviation',    Prefs.objfinder.noiseEstimator = 'std';
                case 'Minimum intensity',     Prefs.objfinder.noiseEstimator = 'min';
            end        
        end
        
        function savePreferences(app)
            Follicles = app.getPrefsFollicles;
            Eyelid    = app.getPrefsEyelid;
            save([userpath filesep 'FollicleFinder' filesep 'Settings.mat'], 'Follicles', 'Eyelid');
        end
        
        function loadPreferences(app)
            if ~exist([userpath filesep 'FollicleFinder'], 'dir')
                mkdir([userpath filesep 'FollicleFinder']);
            end
            if ~exist([userpath filesep 'FollicleFinder' filesep 'Settings.mat'], 'file')
                return
            end
            
            Prefs = load([userpath filesep 'FollicleFinder' filesep 'Settings.mat']);
            app.cmbZoomFollicles.Value  = Prefs.Follicles.Zoom;
            app.cmbZoomEyelid.Value     = Prefs.Eyelid.Zoom;
            app.ColorMaybe.Color        = Prefs.Follicles.ColorMaybe*2/256;
            app.ColorFalse.Color        = Prefs.Follicles.ColorFalse*2/256;
            app.ColorTrue.Color         = Prefs.Follicles.ColorValid*2/256;
            app.ColorSelected.Color     = Prefs.Follicles.ColorSelected*2/256;
            
        end

        
        function Obj = generateImgObject(~, ImgFileName, Img)
            % Generates an empty structure in which the properties of
            % objects found in image Img can be stored and a diagnosis can
            % be assigned to the image as well as scoring for trachomatous
            % features (Follicles, Scaring, Papillae, Corneal opacity)
            
            Obj.ImgFile      = ImgFileName;
            Obj.ImgSize      = [size(Img, 1), size(Img, 2)]; % Image size (pixels)
            Obj.ImgRes       = [1 ,1]; % Resolution (mm/pixels)
            Obj.Pos          = [];     % Position of identified objects
            Obj.Vox          = [];     % Pixels belonging to identified objects
            Obj.Filter       = [];     % 1 = valid, -1=false 0 = maybe
            Obj.Shape.Perim  = 0; % Shape properties (perimeter, length, roundness)
            Obj.Shape.LenMaj = 0;
            Obj.Shape.Round  = 0;
            Obj.Scores.F     = 0; % Follicles presence for TF (F0-3)
            Obj.Scores.P     = 0; % Papillary inflammation, sign of TI (P0-3)
            Obj.Scores.C     = 0; % Conjunctival scaring, sign of TS (C0-3)
            Obj.Scores.TE    = 0; % Trichiasis/Entropion, sign of TT (T0-3)
            Obj.Scores.CC    = 0; % Corneal scarring opacity for CC (CC0-3)
            Obj.Diagnosis   = 'TNormal';
        end

        function NN = generateNeuralNet(~, NetName, NetType, ModelName, Net)
            % Generates an empty neural network structure
            
            NN.UID       = generateUID;
            NN.Name      = NetName; 
            NN.Date      = datetime;   % Current date
            NN.Type      = NetType;    % Pixel classifier / Object detector / Object classifier
            NN.Model     = ModelName;  % Name of the network model (alexnet, googlenet, ...)
            NN.Net       = Net;        % Network model
            NN.Trained   = ~isempty(Net);
        end
    
        
        function newPath = validatePath(~, fPath)
            % Adapt a filesystem path wrote by a different platform into current            
            newPath = fPath;
            switch filesep
                case '/' % Unix
                    if contains(fPath, '\')
                        newPath = strrep(fPath, '\', filesep);
                    end
                case '\' % Windows
                    if contains(fPath, '/')
                        newPath = strrep(fPath, '/', filesep);
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dlg = uiprogressdlg(app.UIFigure,'Title','Starting Follicle Finder...','Indeterminate', 'on');            

            movegui(app.UIFigure,'center');
            app.RefreshUserName;
            app.RefreshTrainingTable;
            app.RefreshNeuralNetTable;
            app.loadPreferences;
            
            close(dlg);
        end

        % Button pushed function: btnAutoChooseUser, btnChooseUser
        function btnChooseUserButtonPushed(app, event)
            answer = inputdlg('User name:', 'Select user');
            
            if ~isempty(answer)
                app.UserName = answer{:};
                app.RefreshUserName;
                app.btnPhotosFolder.Enable = 'on';
                app.btnAutoPhotosFolder.Enable = 'on';
            else
                app.btnPhotosFolder.Enable = 'off';
                app.btnTagFollicles.Enable  = 'off';
                app.btnAutoPhotosFolder.Enable = 'off';
            end
            
        end

        % Button pushed function: btnAutoPhotosFolder, 
        % btnPhotosFolder
        function btnPhotosFolderButtonPushed(app, event)
            selpath = uigetdir(pwd, 'Select folder with photos to score');
            if exist(selpath,"dir")
                app.ImagesFolder = selpath;
                app.Log.Value = app.ImagesFolder;
                app.btnTagFollicles.Enable = 'on';
                app.btnTraceEyelid.Enable  = 'on';
                app.btnAutoTagFollicles.Enable = 'on';
            else
                app.ImagesFolder = '';
                app.btnTagFollicles.Enable = 'off';
                app.btnTraceEyelid.Enable  = 'off';
                app.btnAutoTagFollicles.Enable = 'off';
            end
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: btnTagFollicles
        function btnTagFolliclesButtonPushed(app, event)
            app.Log.Value = 'Manually identifying follicles in the training set...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
                        
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end                
            end

            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Manual';
            Training.Date           = datetime;
            Training.User           = app.UserName;
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.ImagesTODOEyelid    = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            % If user selected a training, ask if to edit or start anew
            TODOmode = 'New';
            if ~isempty(app.RowSelected)
                switch questdlg('Existing training selected: Complete unfinished, review analysed images or start anew?','Training selection', 'Complete', 'Review', 'New', 'Complete')
                    case 'Complete'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Complete';
                    case 'Review'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Review';
                end    
            end                      
            
            switch TODOmode
                case {'New', 'Complete'}
                    TODOlist = Training.ImagesTODOFollicles;
                case 'Review'
                    TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOFollicles);
            end
            
            % Propose one by one each image to the user to score
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                
                try
                    Img = imread(ImgFileName);
                    Img = Img(:,:,1:3); % Ensure image only has RGB and not RGBA
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                
                switch TODOmode
                    case {'New', 'Complete'}
                        % Display custom UI to segment objects of interest
                        Follicles = app.generateImgObject(ImgFileName, Img);
                        F = inspectPhoto(Img, Follicles, app.getPrefsFollicles);
                        
                        if ~isempty(F)
                            % Save the new manual training to disk
                            if isempty(Training.Follicles)
                                Training.Follicles = F;
                            else
                                Training.Follicles(end+1) = F;
                            end
                            Training.ImagesTODOFollicles = setdiff(Training.ImagesTODOFollicles, ImgFileName);
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                    case {'Review'}
                        % Find objects belonging to current image
                        Found = 0;
                        for j = 1:numel(Training.Follicles)
                            if strcmp(Training.Follicles(j).ImgFile, ImgFileName)
                                Follicles = Training.Follicles(j);
                                Found = j;
                                break
                            end
                        end                                  
                        if Found == 0
                            disp(['Cannot find the follicles detected for ' ImgFileName]);
                            break
                        end

                        % Display custom UI to segment objects of interest
                        F = inspectPhoto(Img, Follicles, app.getPrefsFollicles);
                       
                        if ~isempty(F)
                            % Save the new manual training to disk
                            Training.Follicles(Found) = F;
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                end
            end

            app.RefreshTrainingTable;            
            app.Log.Value = 'Manually identifying follicles in the training set...DONE';
            app.Lamp.Color = 'Green';
        end

        % Button pushed function: btnTraceEyelid
        function btnTraceEyelidButtonPushed(app, event)
            app.Log.Value = 'Manually identifying eyelid in the training set...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
                        
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end                
            end

            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Manual';
            Training.Date           = datetime;
            Training.User           = app.UserName;
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.ImagesTODOEyelid    = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            % If user selected a training, ask if to edit or start anew
            TODOmode = 'New';
            if ~isempty(app.RowSelected)
                switch questdlg('Existing training selected: Complete unfinished, review analysed images or start anew?','Training selection', 'Complete', 'Review', 'New', 'Complete')
                    case 'Complete'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Complete';
                    case 'Review'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Review';
                end    
            end                      
            
            switch TODOmode
                case {'New', 'Complete'}
                    TODOlist = Training.ImagesTODOEyelid;
                case 'Review'
                    TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOEyelid);
            end
            
            % Propose one by one each image to the user to score
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                
                try
                    Img = imread(ImgFileName);
                    Img = Img(:,:,1:3); % Ensure image only has RGB and not RGBA                    
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                
                switch TODOmode
                    case {'New', 'Complete'}
                        % Display custom UI to segment objects of interest
                        Eyelid = app.generateImgObject(ImgFileName, Img);
                        F = inspectPhoto(Img, Eyelid, app.getPrefsEyelid);
                        
                        if ~isempty(F)
                            % Save the new manual training to disk
                            if isempty(Training.Eyelid)
                                Training.Eyelid = F;
                            else
                                Training.Eyelid(end+1) = F;
                            end
                            Training.ImagesTODOEyelid = setdiff(Training.ImagesTODOEyelid, ImgFileName);
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                    case {'Review'}
                        % Find objects belonging to current image
                        Found = 0;
                        for j = 1:numel(Training.Eyelid)
                            if strcmp(Training.Eyelid(j).ImgFile, ImgFileName)
                                Eyelid = Training.Eyelid(j);
                                Found = j;
                                break
                            end
                        end                                  
                        if Found == 0
                            disp(['Cannot find the eyelid detected for ' ImgFileName]);
                            break
                        end

                        % Display custom UI to segment objects of interest
                        F = inspectPhoto(Img, Eyelid, app.getPrefsEyelid);
                        
                        if ~isempty(F)
                            % Save the new manual training to disk
                            Training.Eyelid(Found) = F;
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                end
            end

            app.RefreshTrainingTable;            
            app.Log.Value = 'Manually identifying eyelid in the training set...DONE';
            app.Lamp.Color = 'Green';
        end

        % Cell selection callback: tblTraining
        function tblTrainingCellSelection(app, event)
            % Store the row selected by user
            app.RowSelected = event.Indices;
            if isempty(app.RowSelected)
                return
            end
            
            app.btnDeleteRow.Enable             = 'on';
            app.btnTagFollicles.Enable          = 'on';
            app.btnTraceEyelid.Enable           = 'on';
            app.btnExportTraining.Enable        = 'on';
            app.btnValidateFollicles.Enable     = 'on';
            app.btnBatchMaskEyelid.Enable       = 'on';
            app.btnBatchMaskFollicles.Enable    = 'on';
            app.btnBatchSaveEachFollicle.Enable = 'on';
            app.btnBatchSaveBboxes.Enable       = 'on';
            app.btnRelinkImages.Enable          = 'on';
            
            if size(app.RowSelected,1) > 1
               app.btnMergeRecords.Enable       = 'on'; 
            else
                app.btnMergeRecords.Enable      = 'off';
            end
        end

        % Button pushed function: btnDeleteRow
        function btnDeleteRowButtonPushed(app, event)
            if ~isempty(app.RowSelected)
                switch questdlg('Do you really want to permanently remove selected training session?','Delete training', 'No', 'Yes', 'No')
                    case 'Yes'
                    app.Log.Value = 'Deleting item from disk...';
                    app.Lamp.Color = 'Yellow'; pause(0.2);
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting records...','Indeterminate', 'on');            

                    for i = 1:size(app.RowSelected,1)
                        UID = app.tblTraining.Data{app.RowSelected(i,1),6}; % Retrieve UID
                        deleteTraining(UID{:}); % Delete saved training file matching UID
                    end
                    app.RefreshTrainingTable;

                    close(dlg);
                    app.Log.Value = 'Deleting item from disk...DONE';
                    app.Lamp.Color = 'Green'; pause(0.2);
                    
                end
            end            
        end

        % Button pushed function: btnRefreshTable
        function btnRefreshTableButtonPushed(app, event)
            app.Log.Value = 'Refreshing table...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            app.RefreshTrainingTable;
            app.RefreshNeuralNetTable;
            
            app.Log.Value = 'Refreshing table...DONE';
            app.Lamp.Color = 'Green'; pause(0.2);
            
        end

        % Button pushed function: btnColorSelected
        function btnColorSelectedButtonPushed(app, event)
            s = settings;            
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';        
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorSelected.Color, 'Select color');
            if numel(Color)==3
                app.ColorSelected.Color = Color;
                app.savePreferences;
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: btnColorTrue
        function btnColorTrueButtonPushed(app, event)
            s = settings;            
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';        
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorTrue.Color, 'Select color');
            if numel(Color)==3
                app.ColorTrue.Color = Color;
                app.savePreferences;
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';            
        end

        % Button pushed function: btnColorFalse
        function btnColorFalseButtonPushed(app, event)
            s = settings;            
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';        
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorFalse.Color, 'Select color');
            if numel(Color)==3
                app.ColorFalse.Color = Color; 
                app.savePreferences;
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';            
        end

        % Button pushed function: btnColorMaybe
        function btnColorMaybeButtonPushed(app, event)
            s = settings;            
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';        
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorMaybe.Color, 'Select color');
            if numel(Color)==3
                app.ColorMaybe.Color = Color; 
                app.savePreferences;
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';            
        end

        % Button pushed function: btnRefreshTable_2
        function btnRefreshTable_2ButtonPushed(app, event)
            % Reset to default settings values
            app.cmbZoomFollicles.Value  = 2;
            app.cmbZoomEyelid.Value     = 1;
            app.ColorSelected.Color     = [1 0 1];
            app.ColorTrue.Color         = [0 1 0];
            app.ColorFalse.Color        = [1 0 0];
            app.ColorMaybe.Color        = [1 1 0];   
            app.savePreferences;            
        end

        % Value changed function: cmbZoomFollicles
        function cmbZoomFolliclesValueChanged(app, event)
            app.savePreferences;
        end

        % Value changed function: cmbZoomEyelid
        function cmbZoomEyelidValueChanged(app, event)
            app.savePreferences;            
        end

        % Button pushed function: btnAutoTagFollicles
        function btnAutoTagFolliclesButtonPushed(app, event)
            app.Log.Value = 'Automatically identifying follicles in photos...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
                        
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end                
            end

            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Auto';
            Training.Date           = datetime;
            Training.User           = app.UserName;
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.ImagesTODOEyelid    = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            
            % Process one by one each image
            TODOlist = Training.ImagesTODOFollicles;
            dlg = uiprogressdlg(app.UIFigure,'Title','Processing photos...','Value',0, 'Cancelable', 'on');            
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(TODOlist)) ' : ' ImgFileName];
                dlg.Value = i/numel(TODOlist);
                try
                    Img = imread(ImgFileName);
                    Img = Img(:,:,1:3); % Ensure image only has RGB and not RGBA                    
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                                                
                % Display custom UI to segment objects of interest
                Prefs = app.getPrefsAutoFollicles;
                Prefs.objfinder.maxDotSize = app.cmbMaxSize.Value; %TODO convert from micron to px using resolution
                Prefs.objfinder.minDotSize = app.cmbMinSize.Value; %TODO convert from micron to px using resolution
                F = findObjects2D(Img, app.getPrefsAutoFollicles);            
                
                if ~isempty(F)
                    % Add missing fields to the results
                    F.ImgFile   = ImgFileName;
                    F.ImgSize   = [size(Img, 1), size(Img, 2)]; % Image size (pixels)
                    F.ImgRes    = [1 ,1]; % Resolution (mm/pixels)
                    F.Filter    = ones(numel(F.Vox));
                    F.Shape.Perim  = 0; % Shape properties - perimeter
                    F.Shape.LenMaj = 0; % Shape properties - length of majoraxis
                    F.Shape.Round  = 0; % Shape properties - roundness
                    F.Scores.F  = 0;
                    F.Scores.P  = 0;
                    F.Scores.C  = 0;
                    F.Scores.TE = 0;
                    F.Scores.CC = 0;
                    F.Diagnosis = 'TNormal';

                    % Save the new manual training to disk
                    if isempty(Training.Follicles)
                        Training.Follicles = F;
                    else
                        Training.Follicles(end+1) = F;
                    end
                    Training.ImagesTODOFollicles = setdiff(Training.ImagesTODOFollicles, ImgFileName);
                    saveTraining(Training);
                else
                    disp(['Error processing ' ImgFileName ', continuing with the next image.']);
                    continue
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end                
            end
            
            close(dlg);
            app.RefreshTrainingTable;                        
            app.Lamp.Color = 'Green'; pause(0.2);
            app.Log.Value = 'Automatically identifying follicles in photos...DONE';            
        end

        % Button pushed function: btnNetGTruth
        function btnNetGTruthButtonPushed(app, event)
            app.Log.Value = 'Generating training dataset...';
            app.Lamp.Color = 'yellow';  pause(0.2);

            switch app.cmbNetType.Value
                case 'Pixel classifier'
%                     %Standard write just eyelids.
%                     app.Log.Value = 'Select MATLAB training data file';
%                     file = uigetfile;
%                     load(file,"Eyelid");
%                     app.Log.Value = 'Select destination folder for ground truth data';
%                     GTdestinationFolder = uigetdir;
%                     app.Log.Value = 'Processing ground truth data...';
%                     for j=1:numel(Eyelid)
%                         
%                         thatColumn = Eyelid(j).Vox.Pos(:,1);
%                         a = zeros(3000,4000);
%                         
%                         for k = 1:numel(thatColumn)
%                             x=Eyelid(j).Vox.Pos(k);
%                             y=Eyelid(j).Vox.Pos(k,2);
%                             a(x,y) = 255;
%                         end
%                         
%                         if ~exist(GTdestinationFolder, 'dir')
%                             mkdir(GTdestinationFolder);
%                         end
%                         
%                         %baseFile = num2str("imgFile" + j);                        
%                         %outputBaseName = [baseFile,'.JPG'];
%                         [~, fName, fExt] = fileparts(Eyelid(j).ImgFile);
%                         fullDestinationFileName = fullfile(GTdestinationFolder, [fName fExt]);
%                         a = imresize(a, [750 1000]);
%                         imwrite(uint8(a), fullDestinationFileName);
%                     end
                    
% %%%%%%                    Write eyelid and follicle mask

%BUG: Follicle(j) is not the same as Eyelid(j) so follicles from one image
%are being written onto the eyelid of another.(FIXED)
%
                    file = uigetfile;
                    load(file,"Eyelid","Follicles");
                    app.Log.Value = 'Select destination folder for ground truth data';
                    GTdestinationFolder = uigetdir;
                     app.Log.Value = 'Processing ground truth data...';
                    for j=1:numel(Eyelid)
                        
                        eyelids = Eyelid(j).Vox.Pos(:,1); 
                        a = zeros(3000,4000);
                        tempString = Eyelid(j).ImgFile;
                        tempArray = {Follicles.ImgFile};
                        [~, fName, fExt] = fileparts(Eyelid(j).ImgFile);
                        follicleInd = find(strcmp(tempArray,tempString));
%                       Follicles.ImgFile(Follicles.ImgFile == Eyelid(j).ImgFile);
                        for k = 1:numel(eyelids)
                            x=Eyelid(j).Vox.Pos(k);
                            y=Eyelid(j).Vox.Pos(k,2);
                            a(x,y) = 255;     
                        end
                        if(~isempty(Follicles(follicleInd).Vox))
                            follicles = Follicles(follicleInd).Vox();

                            for l=1:numel(follicles)
                                for h=1: numel(Follicles(follicleInd).Vox(l).Pos(:,1))
                                    x=Follicles(follicleInd).Vox(l).Pos(h);
                                    y=Follicles(follicleInd).Vox(l).Pos(h,2);
                                    a(x,y) = 125;
                                end
                            end
                        end
                        
%                         if ~exist(GTdestinationFolder, 'dir')
%                             mkdir(GTdestinationFolder);
%                         end
                        
                        fullDestinationFileName = fullfile(GTdestinationFolder, [fName fExt]);
                        a = imresize(a, [1000 1500]);
                        imwrite(uint8(a), fullDestinationFileName);
                    end


% %%%%%                    resize images and write with proper filenames.
                    app.Log.Value = 'Select MATLAB training data file';
                    sourcefolder = uigetdir;
                    file = uigetfile;
                    load(file,"Eyelid");
                    app.Log.Value = 'Select destination folder for ground truth data';
                    GTdestinationFolder = uigetdir;
                    app.Log.Value = 'Processing ground truth data...';
                    for j=1:numel(Eyelid)
                        [~, fName, fExt] = fileparts(Eyelid(j).ImgFile);
                        a = imread(fullfile(sourcefolder,[fName fExt]));

                        if ~exist(GTdestinationFolder, 'dir')
                            mkdir(GTdestinationFolder);
                        end
                        
                        %baseFile = num2str("imgFile" + j);                        
                        %outputBaseName = [baseFile,'.JPG'];
                        
                        fullDestinationFileName = fullfile(GTdestinationFolder, [fName fExt]);
                        a = imresize(a, [1000 1332]);
                        imwrite(uint8(a), fullDestinationFileName);
                    end


%                     app.Log.Value = 'please select the image folder for training images';
                    TrainingImagesFolder = uigetdir;
                    app.Log.Value = 'please select the destination folder for resized training images';
                    destinationFolder = uigetdir;
                    if ~exist(destinationFolder, 'dir')
                        mkdir(destinationFolder);
                    end
                    Files = dir(TrainingImagesFolder);
                    for k=3:length(Files)
                        
                        baseFile = num2str("imgFile" + k);
                        
                        outputBaseName = [baseFile,'.JPG'];
                        fullDestinationFileName = fullfile(destinationFolder, outputBaseName);
                        
                        FileNames=Files(k).name;
                        I = imread(strcat(strcat(TrainingImagesFolder,'\'),FileNames));
                        I = imresize(I,[750 1000]);
                        imwrite(uint8(I), fullDestinationFileName);
                    end
%             dataSetDir = fullfile('C:\Users\ndennis\Documents\MATLAB\FollicleFinder\Training\Images\');
%             % Store each object as a separate image in the proper training subfolder
%             for exp = 1:numel(app.lstTraining.Items)
%                 
%                 app.lstTraining.Value = app.lstTraining.Items{exp};
%                 cd(app.lstTraining.Items{exp});
% 
%                 Identify which object set(s) to use
%                 if app.chkTrainingObjectsAll.Value
%                     ObjList = listObjects;
%                 elseif ~isempty(app.cmbTrainingObjects.Value)
%                     ObjList = intersect(listObjects, app.cmbTrainingObjects.Value);
%                 else
%                     ObjList = {};
%                 end
%                 
%                 disp(['Processing ' ObjList{:} ' found in ' app.lstTraining.Items{exp}]);
% 
%                 Load Image Matrix of each object, save each axis MIP to Noise or Cell Folder
%                 for i = 1:numel(ObjList)
%                     Objs = loadObjects(ObjList{i});
%                     
%                     for j = 1:numel(Objs.Vox)
%                         Retrieve the intensity values of current object
%                         minPt = min(Objs.Vox(j).Pos(:,1:3));
%                         maxPt = max(Objs.Vox(j).Pos(:,1:3));
%                         imMat = zeros(maxPt-minPt+1, 'uint8');                        
%                         for k = 1:size(Objs.Vox(j).Pos, 1)
%                             pt = Objs.Vox(j).Pos(k,:) - minPt + 1;
%                             imMat(pt(1),pt(2),pt(3)) = Objs.Vox(j).RawBright(k);
%                         end
%                         
%                         Treat differently 2D vs 3D images
%                         x = imresize(imMat, [sz(1) sz(2)]);
%                         I = cat(3,x,x,x);
%                         if size(imMat, 3) == 1
%                             % 2D image: Resize image and encode as RGB
%                             
%                         else    
%                             % 3D image: Create MIPs along cardinal axes, resize and combine into RGB
%                             z = imresize(squeeze(max(imMat,[],3)), [sz(1) sz(2)]);
%                             y = imresize(squeeze(max(imMat,[],1)), [sz(1) sz(2)]);
%                             x = imresize(squeeze(max(imMat,[],2)), [sz(1) sz(2)]);
%                             I = cat(3,z,y,x);
%                         end
%                         [~,expName,~] = fileparts(app.lstTraining.Items{exp});
%                         objsName = Objs.Name;
%                         
%                         Store the object into 'Object' or 'Noise' according to its valudation status
%                         if(Objs.Filter.passF(j))
%                             imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Object' filesep expName '_' objsName '_' num2str(j) '.tif']);
%                         else
%                             imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Noise' filesep expName '_' objsName '_' num2str(j) '.tif']);
%                         end
%                     end
%                 end
%             end
%                        
                case 'Object classifier'
%      
% % TODO: adjust variale names
% %       TODO: generate snippets of 2D images (now is processing 3D volumes)
%             app.Log.Value = 'Generating training dataset...';
%             app.Lamp.Color = 'yellow';  pause(0.2);
%             
%             % Create individual images for each passing and nonpassing object
%             if ~isfolder([app.TrainingFolder.Value filesep 'Images'])
%                 mkdir([app.TrainingFolder.Value filesep 'Images']);
%                 mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object']);
%                 mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise']);
%             else
%                 rmdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object'], 's');
%                 rmdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise'], 's');
%                 rmdir([app.TrainingFolder.Value filesep 'Images'], 's');
%                 mkdir([app.TrainingFolder.Value filesep 'Images']);
%                 mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object']);
%                 mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise']);
%             end
%             
%             try
%                 switch app.cmbTrainingModel.Value
%                     case 'AlexNet',             net = eval('alexnet');
%                     case 'VGG-16',              net = eval('vgg16');
%                     case 'VGG-19',              net = eval('vgg19');
%                     case 'SqueezeNet',          net = eval('squeezenet');
%                     case 'GoogleNet',           net = eval('googlenet');
%                     case 'Inception-v3',        net = eval('inceptionv3');
%                     case 'ResNet-50',           net = eval('resnet50');
%                     case 'ResNet-101',          net = eval('resnet101');
%                     case 'Inception-ResNet-v2', net = eval('inceptionresnetv2');
%                 end
%                 sz = net.Layers(1).InputSize;
%             catch
%                 msgbox([app.cmbTrainingModel.Value ' not available, please install it using the AddOn explorer'], 'NeuralNet Error', 'error');
%                 app.Lamp.Color = 'red';
%                 return
%             end
%             
%             % Store each object as a separate image in the proper training subfolder
%             for exp = 1:numel(app.lstTraining.Items)
%                 
%                 app.lstTraining.Value = app.lstTraining.Items{exp};
%                 cd(app.lstTraining.Items{exp});
% 
%                 % Identify which object set(s) to use
%                 if app.chkTrainingObjectsAll.Value
%                     ObjList = listObjects;
%                 elseif ~isempty(app.cmbTrainingObjects.Value)
%                     ObjList = intersect(listObjects, app.cmbTrainingObjects.Value);
%                 else
%                     ObjList = {};
%                 end
%                 
%                 disp(['Processing ' ObjList{:} ' found in ' app.lstTraining.Items{exp}]);
% 
%                 % Load Image Matrix of each object, save each axis MIP to Noise or Cell Folder
%                 for i = 1:numel(ObjList)
%                     Objs = loadObjects(ObjList{i});
%                     
%                     for j = 1:numel(Objs.Vox)
%                         % Retrieve the intensity values of current object
%                         minPt = min(Objs.Vox(j).Pos(:,1:3));
%                         maxPt = max(Objs.Vox(j).Pos(:,1:3));
%                         imMat = zeros(maxPt-minPt+1, 'uint8');                        
%                         for k = 1:size(Objs.Vox(j).Pos, 1)
%                             pt = Objs.Vox(j).Pos(k,:) - minPt + 1;
%                             imMat(pt(1),pt(2),pt(3)) = Objs.Vox(j).RawBright(k);
%                         end
%                         
%                         % Treat differently 2D vs 3D images
%                         if size(imMat, 3) == 1
%                             % 2D image: Resize image and encode as RGB
%                             x = imresize(imMat, [sz(1) sz(2)]);
%                             I = cat(3,x,x,x);
%                         else    
%                             % 3D image: Create MIPs along cardinal axes, resize and combine into RGB
%                             z = imresize(squeeze(max(imMat,[],3)), [sz(1) sz(2)]);
%                             y = imresize(squeeze(max(imMat,[],1)), [sz(1) sz(2)]);
%                             x = imresize(squeeze(max(imMat,[],2)), [sz(1) sz(2)]);
%                             I = cat(3,z,y,x);
%                         end
%                         [~,expName,~] = fileparts(app.lstTraining.Items{exp});
%                         objsName = Objs.Name;
%                         
%                         % Store the object into 'Object' or 'Noise' according to its valudation status
%                         if(Objs.Filter.passF(j))
%                             imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Object' filesep expName '_' objsName '_' num2str(j) '.tif']);
%                         else
%                             imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Noise' filesep expName '_' objsName '_' num2str(j) '.tif']);
%                         end
%                     end
%                 end
%             end
            end                                     
            
            app.Log.Value = 'Generating training dataset...DONE';
            app.Lamp.Color = 'green';            
        end

        % Button pushed function: btnNetTrain
        function btnNetTrainButtonPushed(app, event)
            switch app.cmbNetType.Value
                case 'Pixel classifier'
                    
% % % % % % % % %   Training a Eyelid and Follicle Network                  
                    
%                     app.Log.Value = 'select training images folder';
%                     testImagesDir = uigetdir;
%                     app.Log.Value = 'select ground truth images folder';
%                     app.Lamp.Color = 'yellow';
%                     testLabelsDir = uigetdir;
%                     app.Log.Value = 'Training...';
%                     classNames = ["Eyelid", "Follicle", "background"];
%                     labelIDs = [255 125 0];
% %                     imageSize = [1000 1333];
%                     imageAugmenter = imageDataAugmenter( ...
%                         'RandRotation',[-20,20], ...
%                         'RandXReflection',1);
%                     imds = imageDatastore(testImagesDir);
% %                     imds = augmentedImageDatastore(imageSize,imageds,'DataAugmentation',imageAugmenter);
%                     pxdsTruth = pixelLabelDatastore(testLabelsDir,classNames,labelIDs);
%                     
%                     %creating the network
%                     numFilters = 32;
%                     filterSize = 3;
%                     numClasses = 3;
%                     layers = [
%                         imageInputLayer([1000 1332 3])
%                         convolution2dLayer(filterSize,numFilters,'Padding',1)
%                         reluLayer()
%                         maxPooling2dLayer(2,'Stride',2)
%                         convolution2dLayer(filterSize,numFilters,'Padding',1)
%                         reluLayer()
%                         transposedConv2dLayer(4,numFilters,'Stride',2,'Cropping',1);
%                         convolution2dLayer(1,numClasses);
%                         softmaxLayer()
%                         pixelClassificationLayer()
%                         ];
%                     opts = trainingOptions('sgdm', ... 
%                         'InitialLearnRate',1e-4, ...
%                         'MaxEpochs',100, ...
%                         'LearnRateSchedule',"piecewise",...
%                         'LearnRateDropFactor',0.05,...
%                         'Plots','training-progress',...
%                         'MiniBatchSize',5);
%                     %add learnRateSchedule and learnRateDropFactor
%                     trainingData = pixelLabelImageSource(imds,pxdsTruth,'DataAugmentation',imageAugmenter);
%                     tbl = countEachLabel(trainingData);
%                     totalNumberOfPixels = sum(tbl.PixelCount);
%                     frequency = tbl.PixelCount / totalNumberOfPixels;
%                     classWeights = 1./frequency;
%                     layers(end) = pixelClassificationLayer('Classes',tbl.Name,'ClassWeights',classWeights);
%                     net = trainNetwork(trainingData,layers,opts);
%                     app.Log.Value = 'Training successful! Select image for testing';
%                     app.Lamp.Color = 'Green';
                    
                    
% % % % % % % % % % Training an Eyelid only network

                    app.Log.Value = 'select training images folder';
                    testImagesDir = uigetdir;
                    app.Log.Value = 'select ground truth images folder';
                    app.Lamp.Color = 'yellow';
                    testLabelsDir = uigetdir;
                    app.Log.Value = 'Training...';
                    classNames = ["Eyelid", "background"];
                    labelIDs = [255 0];
%                     imageSize = [1000 1333];
                    imageAugmenter = imageDataAugmenter( ...
                        'RandRotation',[-20,20], ...
                        'RandXReflection',1);
                        
                    imds = imageDatastore(testImagesDir);
%                     imds = augmentedImageDatastore(imageSize,imageds,'DataAugmentation',imageAugmenter);
                    pxdsTruth = pixelLabelDatastore(testLabelsDir,classNames,labelIDs);
                    
                    %creating the network
                    numFilters = 48;
                    filterSize = 3;
                    %try larger filter
                    numClasses = 2;
                    layers = [
                        imageInputLayer([1000 1332 3])
                        convolution2dLayer(filterSize,numFilters,'Padding',1)
                        reluLayer()
                        maxPooling2dLayer(2,'Stride',2)
                        convolution2dLayer(filterSize,numFilters,'Padding',1)
                        reluLayer()
                        transposedConv2dLayer(4,numFilters,'Stride',2,'Cropping',1);
                        convolution2dLayer(1,numClasses);
                        softmaxLayer()
                        pixelClassificationLayer()
                        ];
                    opts = trainingOptions('sgdm', ... 
                        'InitialLearnRate',1e-4, ...
                        'MaxEpochs',100, ...
                        'LearnRateSchedule',"piecewise",...
                        'LearnRateDropFactor',0.9,...
                        'Plots','training-progress',...
                        'MiniBatchSize',8);
                    %add learnRateSchedule and learnRateDropFactor
                    trainingData = pixelLabelImageSource(imds,pxdsTruth,'DataAugmentation',imageAugmenter);
                    tbl = countEachLabel(trainingData);
                    totalNumberOfPixels = sum(tbl.PixelCount);
                    frequency = tbl.PixelCount / totalNumberOfPixels;
                    classWeights = 1./frequency;
                    layers(end) = pixelClassificationLayer('Classes',tbl.Name,'ClassWeights',classWeights);
                    net = trainNetwork(trainingData,layers,opts);
                    app.Log.Value = 'Training successful! Select image for testing';
                    app.Lamp.Color = 'Green';
                    
                    
                    % Save trained network to disk
                    UID         = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                    NN          = loadNeuralNet(UID{:});
                    NN.Net      = net;
                    NN.Trained  = true;
                    NN.Date     = datetime;
                    saveNeuralNet(NN);
                    app.RefreshNeuralNetTable;
                case 'Object classifier'
% train net with follicle and Eyelid data
% % app.Log.Value = 'select training images folder';
%                     testImagesDir = uigetdir;
% %                     app.Log.Value = 'select ground truth images folder';
% %                     app.Lamp.Color = 'yellow';
%                     testLabelsDir = uigetdir;
% %                     app.Log.Value = 'Training...';
%                     classNames = ["Eyelid","Follicle","background"];
%                     labelIDs = [255 125 0];
%                     imds = imageDatastore(testImagesDir);
%                     pxdsTruth = pixelLabelDatastore(testLabelsDir,classNames,labelIDs);
%                     
%                     %creating the network
%                     numFilters = 32;
%                     filterSize = 3;
%                     numClasses = 3;
%                     layers = [
%                         imageInputLayer([750 1000 3])
%                         convolution2dLayer(filterSize,numFilters,'Padding',1)
%                         reluLayer()
%                         maxPooling2dLayer(2,'Stride',2)
%                         convolution2dLayer(filterSize,numFilters,'Padding',1)
%                         reluLayer()
%                         transposedConv2dLayer(4,numFilters,'Stride',2,'Cropping',1);
%                         convolution2dLayer(1,numClasses);
%                         softmaxLayer()
%                         pixelClassificationLayer()
%                         ];
%                     opts = trainingOptions('sgdm', ...
%                         'InitialLearnRate',1e-3, ...
%                         'MaxEpochs',50, ...
%                         'Plots','training-progress',...
%                         'MiniBatchSize',10);
%                     trainingData = pixelLabelImageDatastore(imds,pxdsTruth);
%                     tbl = countEachLabel(trainingData);
%                     totalNumberOfPixels = sum(tbl.PixelCount);
%                     frequency = tbl.PixelCount / totalNumberOfPixels;
%                     classWeights = 2./frequency;
%                     layers(end) = pixelClassificationLayer('Classes',tbl.Name,'ClassWeights',classWeights);
%                     net = trainNetwork(trainingData,layers,opts);
% %                     app.Log.Value = 'Training successful! Select image for testing';
% %                     app.Lamp.Color = 'Green';
            end

        end

        % Button pushed function: btnNetTest
        function btnNetTestButtonPushed(app, event)
            switch app.cmbNetType.Value                
                case 'Pixel classifier'
                    if ~isempty(app.RowSelected) && ~isempty(app.NetSelected)
                        UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                        NN = loadNeuralNet(UID);
                        
                        UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID
                        Training = loadTraining(UID);
                        
                        dstFolder       = uigetdir(pwd,"Select test results destination folder");
                        app.UIFigure.Visible = 'off';
                        app.UIFigure.Visible = 'on';            
                        
                        app.Log.Value   = "Predicting pixel identities...";
                        app.Lamp.Color  = "yellow";
                        Accuracy        = zeros(numel(Training.Eyelid),1);
                        FileNames       = {};

                        dlg = uiprogressdlg(app.UIFigure,'Title','Prediction pixel identities...','Value',0, 'Cancelable', 'on');                         
                        for i = 1: numel(Training.Eyelid)
                            [~, fName, fExt] = fileparts(Training.Eyelid(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Eyelid)) ' : ' fName];
                            dlg.Value = i/numel(Training.Eyelid);
                            
                            % Load original image and predict pixel identity
                            srcImage = imread(Training.Eyelid(i).ImgFile);                            
                            Prediction = semanticseg(srcImage, NN.Net);                                                        

                            % Convert predicted categories to a binary mask
                            MaskPredicted = zeros(1500,2000);                            
                            MaskPredicted(Prediction == "Eyelid") = 1;
                            MaskPredicted = medfilt2(MaskPredicted);
                            %MaskPredicted = imclose(MaskPredicted, strel('disk',10));
                            MaskPredicted = imfill(MaskPredicted);
                            
                            % Keep only largest connected object in mask (eyelid)
                            Labels = bwlabel(MaskPredicted,8);
                            LargestLabel = mode(Labels(Labels ~= mode(Labels, 'all')), 'all');                            
                            MaskPredicted(Labels ~= LargestLabel) = 0;
                            
                            % Calculate prediction accuracy
                            GTEyelidPx      = Training.Eyelid(i).Vox.Ind;
                            SharedEyelidPx  = intersect(find(MaskPredicted),GTEyelidPx);                            
                            Accuracy(i)     = numel(SharedEyelidPx)/numel(GTEyelidPx);
                            
                            % Create the ground truth eyelid mask
                            MaskGT = zeros(Training.Eyelid(i).ImgSize);
                            MaskGT(Training.Eyelid(i).Vox.Ind) = 255;

                            % Save predicted and ground truth eyelid masks as images in dstFolder
                            imwrite(MaskPredicted, fullfile(dstFolder, [fName '_Predicted' fExt]));                            
                            imwrite(MaskGT, fullfile(dstFolder, [fName '_GroundTruth' fExt]));                            
                            if isempty(FileNames)
                                FileNames = {fName};
                            else
                                FileNames{end+1} = fName; %#ok
                            end
                            
                            disp(['Filename: ' fName ', Prediction accuracy:' num2str(Accuracy(i))]);
                            drawnow;
                            
                            if dlg.CancelRequested
                                break % User wants to interrupt computation
                            end                            
                        end
                        close(dlg);
                    end
                    FileNames = FileNames';
                    T = table(FileNames, Accuracy);
                    writetable(T,[dstFolder filesep 'validationData.xls']);

                    app.Log.Value = "Predicting pixel identities...DONE";
                    app.Lamp.Color = "green";
            end
        end

        % Button pushed function: btnNetCreate
        function btnNetCreateButtonPushed(app, event)
            switch app.cmbNetModel.Value
                case 'Nico'
                    % add here Nico's net model
                    net = [];
                case 'Alexnet'
                    net = alexnet;
                case 'VGG-16'
                    net = vgg16;
                case 'YOLOv2'
                    net = yolov2ObjectDetector;
            end
            
            NN = app.generateNeuralNet('New NeuralNet', app.cmbNetType.Value, app.cmbNetModel.Value, net);           
            saveNeuralNet(NN); 
            app.RefreshNeuralNetTable;
        end

        % Cell selection callback: tblNeuralNet
        function tblNeuralNetCellSelection(app, event)
            % Store the row selected by user
            app.NetSelected = event.Indices;
            if ~isempty(app.NetSelected)
                NetName = app.tblNeuralNet.Data{app.NetSelected(:,1),1};
                if app.tblNeuralNet.Data{app.NetSelected(:,1),5}
                    NetTrained = 'on';
                else
                    NetTrained = 'off';
                end
                
                app.btnNetDelete.Enable     = 'on';
                app.btnNetClone.Enable      = 'on';
                app.btnNetImport.Enable     = 'on';
                app.btnNetExport.Enable     = 'on';
                app.btnNetInfo.Enable       = 'on';
                app.btnNetGTruth.Enable     = 'on';
                app.btnNetTrain.Enable      = 'on';
                app.btnNetTest.Enable       = NetTrained;                
                app.txtNetName.Value        = NetName{:};
                app.cmbNetType.Value        = app.tblNeuralNet.Data{app.NetSelected(:,1),2};
                app.cmbNetModel.Value       = app.tblNeuralNet.Data{app.NetSelected(:,1),3};
            end
        end

        % Button pushed function: btnNetDelete
        function btnNetDeleteButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                switch questdlg('Do you really want to permanently remove selected NeuralNet?','Delete NeuralNet', 'No', 'Yes', 'No')
                    case 'Yes'
                    app.Log.Value = 'Deleting item from disk...';
                    app.Lamp.Color = 'Yellow'; pause(0.2);
                    
                    UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                    deleteNeuralNet(UID{:}); % Delete saved training file matching UID
                    app.NetSelected = [];
                    app.RefreshNeuralNetTable;

                    app.Log.Value = 'Deleting item from disk...DONE';
                    app.Lamp.Color = 'Green'; pause(0.2);                    
                end
            end                        
        end

        % Button pushed function: btnNetClone
        function btnNetCloneButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                app.Log.Value = 'Cloning NeuralNet...';
                app.Lamp.Color = 'Yellow'; pause(0.2);
                
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                NN = loadNeuralNet(UID);
                NN.UID = generateUID;
                NN.Name = [NN.Name ' (copy)'];
                saveNeuralNet(NN);
                app.NetSelected = [];
                app.RefreshNeuralNetTable;
                
                app.Log.Value = 'Cloning NeuralNet...DONE';
                app.Lamp.Color = 'Green'; pause(0.2);
            end
        end

        % Value changed function: txtNetName
        function txtNetNameValueChanged(app, event)
            if ~isempty(app.NetSelected)
                app.Log.Value = 'Saving renamed NeuralNet...';
                app.Lamp.Color = 'Yellow'; pause(0.2);
                
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                NN.UID = UID{:};
                NN.Name = app.txtNetName.Value;
                saveNeuralNet(NN, 'Name');
                app.RefreshNeuralNetTable;
            end
            
            app.Log.Value = 'Saving renamed NeuralNet...DONE';
            app.Lamp.Color = 'Green'; pause(0.2);
        end

        % Button pushed function: btnNetInfo
        function btnNetInfoButtonPushed(app, event)
            app.Log.Value = 'Retrieving NeuralNet architecture...';
            app.Lamp.Color = 'yellow';  pause(0.2);

            if ~isempty(app.NetSelected)                
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                NN = loadNeuralNet(UID);
                analyzeNetwork(NN.Net.Layers);
            end     
            
            app.Log.Value = 'Retrieving NeuralNet architecture...DONE';
            app.Lamp.Color = 'green';              
        end

        % Button pushed function: btnNetDesign
        function btnNetDesignButtonPushed(app, event)
            app.Log.Value = 'Starting Deep Network Designer...';
            app.Lamp.Color = 'yellow';  pause(0.2);

            % If a NeuralNet is selected, export it to the base workspace
            % to make it accessible to the Deep Network Designer
            if ~isempty(app.NetSelected)
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                NN = loadNeuralNet(UID);
                assignin('base', 'net', NN.Net)
            end
            
            deepNetworkDesigner;
            
            app.Log.Value = 'Starting Deep Network Designer...DONE';
            app.Lamp.Color = 'green';             
        end

        % Button pushed function: btnNetImport
        function btnNetImportButtonPushed(app, event)
            items       = evalin('base', 'who');
            itemNum     = listdlg('PromptString','Select NeuralNet to import','SelectionMode','single', 'ListString',items);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % Import selected object
            if isempty(itemNum)
                return % user aborted
            else
                netLayers   = evalin('base',items{itemNum});
            end
            
            % Ensure selected item is a valid NeuralNet Layers object
            if ~contains(class(netLayers), 'Layer')
                return 
            end
            
            % Replace selected NeuralNet Layers with the one imported            
            app.Log.Value = 'Importing NeuralNet layers...';
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            if ~isempty(app.NetSelected)
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                NN = loadNeuralNet(UID);
                NN.Net.Layers = netLayers;
                saveNeuralNet(NN);
            end  
            
            app.Log.Value = 'Importing NeuralNet layers...DONE';
            app.Lamp.Color = 'green';                      
        end

        % Button pushed function: btnBatchResize
        function btnBatchResizeButtonPushed(app, event)
            % Identify image files to score and store path to them in a list
            srcFolder = uigetdir([], 'Source folder'); 
            files = dir(srcFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)

            dstFolder = uigetdir([], 'Destination folder');
            if ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end            
            
            S.Xresolution = 1024;
            S.Yresolution = 768;
            S = editStruct(S, 'Target size (px)');            
            
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end                
            end

            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';            
            app.Log.Value = 'Resizing images...';
            app.Lamp.Color = 'yellow';  pause(0.2);

            dlg = uiprogressdlg(app.UIFigure,'Title','Resizing photos...','Value',0, 'Cancelable', 'on'); 
            for i = 1:numel(lstImages)
                [~, fName, fExt] = fileparts(lstImages{i});
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(lstImages)) ' : ' fName];
                dlg.Value = i/numel(lstImages);

                try
                    srcImg = imread(lstImages{i});
                    dstImg = imresize(srcImg(:,:,1:3), [S.Yresolution S.Xresolution], 'nearest');
                    imwrite(dstImg, fullfile(dstFolder, [fName '.tif']));
                catch
                    disp(['Error processing ' fName fExt]);
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end                                
            end
            close(dlg);
            
            app.Log.Value   = 'Resizing images...DONE';
            app.Lamp.Color  = 'green';              
        end

        % Button pushed function: btnBatchMaskEyelid
        function btnBatchMaskEyelidButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID
                Training = loadTraining(UID{:});
            end

            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';            

            if dstFolder == 0 
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
                        
            app.Log.Value = 'Extracting eyelid masks...';
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            % Extract each eyelid region as a binary image TIF
            dlg = uiprogressdlg(app.UIFigure,'Title','Extracting eyelids...','Value',0, 'Cancelable', 'on');             
            for i = 1:numel(Training.Eyelid)
                [~, fName, ~] = fileparts(Training.Eyelid(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Eyelid)) ' : ' fName];
                dlg.Value = i/numel(Training.Eyelid);
                
                I = zeros(Training.Eyelid(i).ImgSize);
                I(Training.Eyelid(i).Vox.Ind) = 255;
                imwrite(I, fullfile(dstFolder, [fName '.JPG']));
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end                                                
            end           
            close(dlg);            

            app.Log.Value   = 'Extracting eyelid masks...DONE';
            app.Lamp.Color  = 'green';                          
        end

        % Button pushed function: btnBatchSaveEachFollicle
        function btnBatchSaveEachFollicleButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID
                Training = loadTraining(UID{:});
            end

            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';   

            if dstFolder == 0 
                return
            end
            if ~exist(dstFolder, 'dir'), mkdir(dstFolder); end
            if ~exist([dstFolder filesep 'True'], 'dir'), mkdir([dstFolder filesep 'True']);end
            if ~exist([dstFolder filesep 'False'], 'dir'), mkdir([dstFolder filesep 'False']);end
            if ~exist([dstFolder filesep 'Maybe'], 'dir'), mkdir([dstFolder filesep 'Maybe']);end                                    
         
            app.Log.Value = 'Saving individual follicles...';
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            % Extract each eyelid region as a binary image TIF
            dlg = uiprogressdlg(app.UIFigure,'Title','Extracting follicles...','Value',0, 'Cancelable', 'on');             
            for i = 1:numel(Training.Follicles)
                [~, fName, ~] = fileparts(Training.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName];
                dlg.Value = i/numel(Training.Follicles);
                
                for f = 1:numel(Training.Follicles(i).Vox)
                    % Retrieve current Follicle's pixel data
                    F = Training.Follicles(i).Vox(f); 
                    
                    % Retrieve the intensity values of current follicle
                    minPt = min(F.Pos(:,1:2));
                    maxPt = max(F.Pos(:,1:2));
                    I = zeros(maxPt-minPt+1, 'uint8');
                    for k = 1:size(F.Pos, 1)
                        pt = F.Pos(k,:) - minPt + 1;
                        I(pt(1),pt(2)) = F.RawBright(k);
                    end

                    % Store the follicles into 'True/False/Maybe' folder based upon their valudation status
                    if Training.Follicles(i).Filter(f) == 1
                        imwrite(I, [dstFolder filesep 'True' filesep Training.User '_img' fName '_follicle' num2str(f) '.tif']);
                    elseif Training.Follicles(i).Filter(f) == -1
                        imwrite(I, [dstFolder filesep 'False' filesep Training.User '_img' fName '_follicle' num2str(f) '.tif']);
                    elseif Training.Follicles(i).Filter(f) == 0
                        imwrite(I, [dstFolder filesep 'Maybe' filesep Training.User '_img' fName '_follicle' num2str(f) '.tif']);
                    end                    
                    imwrite(I, fullfile(dstFolder, [fName '.tif']));
                end  
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end           
            close(dlg);            

            app.Log.Value   = 'Saving individual follicles...DONE';
            app.Lamp.Color  = 'green';    
        end

        % Button pushed function: btnBatchMaskFollicles
        function btnBatchMaskFolliclesButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID
                Training = loadTraining(UID{:});
            end

            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            if dstFolder == 0 
                    return 
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
                        
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';        
            
            app.Log.Value = 'Extracting follicle masks...';
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            % Extract each eyelid region as a binary image TIF
            dlg = uiprogressdlg(app.UIFigure,'Title','Extracting follicles...','Value',0, 'Cancelable', 'on');             
            for i = 1:numel(Training.Follicles)
                [~, fName, ~] = fileparts(Training.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName];
                dlg.Value = i/numel(Training.Follicles);
                
                I = zeros(Training.Follicles(i).ImgSize);
                for f = 1:numel(Training.Follicles(i).Vox)
                    I(Training.Follicles(i).Vox(f).Ind) = 255;
                end
                imwrite(I, fullfile(dstFolder, [fName '.tif']));
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end                                                
            end           
            close(dlg);            

            app.Log.Value   = 'Extracting follicle masks...DONE';
            app.Lamp.Color  = 'green';                                      
        end

        % Button pushed function: btnExportTraining
        function btnExportTrainingButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            app.Log.Value = 'Exporting training data to disk...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            dlg = uiprogressdlg(app.UIFigure,'Title','Exporting records files...','Indeterminate', 'on');

            for i = 1:size(app.RowSelected,1)
                UID = app.tblTraining.Data{app.RowSelected(i,1),6}; % Retrieve UID
                Training = loadTraining(UID{:});
                
                FileName = [dstFolder filesep Training.UID '.mat'];
                save(FileName, '-struct', 'Training');
            end
            
            close(dlg);
            app.Log.Value = 'Exporting training data to disk...DONE';
            app.Lamp.Color = 'Green'; pause(0.2);                        
        end

        % Button pushed function: btnRelinkImages
        function btnRelinkImagesButtonPushed(app, event)
            % Ensure a training is selected from the table
            if isempty(app.RowSelected)
                return 
            end

            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select images folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                return
            end

            app.Log.Value = 'Relinking image files...';
            app.Lamp.Color = 'yellow';  pause(0.2);
            dlg = uiprogressdlg(app.UIFigure,'Title','Relinking image files...','Indeterminate', 'on');            
            
            for i = 1:size(app.RowSelected,1)
            
                UID = app.tblTraining.Data{app.RowSelected(i,1),6}; % Retrieve UID
                Training = loadTraining(UID{:});
                
                % Replace old image folder path with new dstFolder
                Training.ImagesFolder = dstFolder;
                for j = 1:numel(Training.ImagesList)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.ImagesList{j}));
                    Training.ImagesList{j} = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.ImagesTODOEyelid)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.ImagesTODOEyelid{j}));
                    Training.ImagesTODOEyelid{j} = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.ImagesTODOFollicles)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.ImagesTODOFollicles{j}));
                    Training.ImagesTODOFollicles{j} = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.Follicles)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.Follicles(j).ImgFile));
                    Training.Follicles(j).ImgFile = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.Eyelid)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.Eyelid(j).ImgFile));
                    Training.Eyelid(j).ImgFile = fullfile(dstFolder, [fName fExt]);
                end
                
                saveTraining(Training);
            end
            app.RefreshTrainingTable;
            
            close(dlg);
            app.Log.Value   = 'Relinking image files...DONE';
            app.Lamp.Color  = 'green';               
        end

        % Button pushed function: btnNetExport
        function btnNetExportButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),6}; % Retrieve UID
                NN = loadNeuralNet(UID{:});

                % Choose a folder in which to write all binary masks
                dstFolder = uigetdir([],'Select Destination folder');
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';            
                
                if dstFolder == 0 
                    return 
                elseif ~exist(dstFolder, 'dir')
                    mkdir(dstFolder);
                end

                app.Log.Value = 'Exporting NeuralNet to disk...';
                app.Lamp.Color = 'Yellow'; pause(0.2);
                
                FileName = [dstFolder filesep NN.UID '.mat'];
                save(FileName, '-struct', 'NN');
                
                app.Log.Value = 'Exporting NeuralNet to disk...DONE';
                app.Lamp.Color = 'Green'; pause(0.2);
            end                        
            
        end

        % Button pushed function: btnImportTraining
        function btnImportTrainingButtonPushed(app, event)
            % Choose a folder in which to write all binary masks
            [srcFile, srcPath] = uigetfile('*.mat', 'Select file to import');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if srcFile == 0
                return
            end
            
            app.Log.Value = 'Importing training data from disk...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            % Copy the selected file into local Training repository
            dstPath = [userpath filesep 'FollicleFinder' filesep 'Training'];
            copyfile(fullfile(srcPath, srcFile), fullfile(dstPath, srcFile));
            app.RefreshTrainingTable;
            
            app.Log.Value = 'Importing training data from disk...DONE';
            app.Lamp.Color = 'Green'; pause(0.2);
        end

        % Button pushed function: btnBatchSaveBboxes
        function btnBatchSaveBboxesButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),6}; % Retrieve UID
                Training = loadTraining(UID{:});
            end

            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';   

            if dstFolder == 0 
                return
            elseif ~exist(dstFolder, 'dir') 
                mkdir(dstFolder); 
            end
         
            app.Log.Value = 'Saving follicles bounding boxes...';
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            % Extract each eyelid region as a binary image TIF
            dlg = uiprogressdlg(app.UIFigure,'Title','Saving bounding boxes...','Value',0, 'Cancelable', 'on');             
            for i = 1:numel(Training.Follicles)
                [~, fName, ~] = fileparts(Training.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName];
                dlg.Value = i/numel(Training.Follicles);
                
                tblF = [];
                for f = 1:numel(Training.Follicles(i).Vox)
                    % Retrieve current Follicle's pixel data
                    F = Training.Follicles(i).Vox(f); 
                    
                    % Retrieve bbox boundaries (box must enclose object, hence it has to be 1 pixel wider in each direction)
                    yMin = max(1, min(F.Pos(:,1))-1);
                    yMax = max(F.Pos(:,1))+1;
                    xMin = max(1, min(F.Pos(:,2))-1);
                    xMax = max(F.Pos(:,2))+1;
                    
                    switch Training.Follicles(i).Filter(f)
                        case 1,  fType = 'True';
                        case -1, fType = 'False';
                        case 0,  fType = 'Maybe';
                    end
                    
                    if isempty(tblF)
                        tblF = table(f, {Training.Follicles(i).ImgFile}, xMin, yMin, xMax, yMax, {fType},...
                            'VariableNames', {'Follicle', 'Filename', 'xMin', 'yMin', 'xMax', 'yMax', 'Type'});
                    else
                        tblF = [tblF; table(f, {Training.Follicles(i).ImgFile}, xMin, yMin, xMax, yMax, {fType},...
                            'VariableNames', {'Follicle', 'Filename', 'xMin', 'yMin', 'xMax', 'yMax', 'Type'})]; %#ok
                    end
                end  

                % Write data as an Excel table
                if ~isempty(tblF)
                    tblFileName = fullfile(dstFolder, [fName '.xls']);
                    writetable(tblF, tblFileName);    
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end           
            close(dlg);            


            app.Log.Value   = 'Saving follicles bounding boxes...DONE';
            app.Lamp.Color  = 'green';    
            
        end

        % Button pushed function: btnCompareEyelid
        function btnCompareEyelidButtonPushed(app, event)
            if isempty(app.RowSelected) || size(app.RowSelected, 1) < 2
                app.Log.Value = 'Select Multiple trainings from the list to compare them';
                return
            end

            % Accumulate training sessions to compare
            for row = 1:numel(app.RowSelected(:,1))                
                UID = app.tblTraining.Data{app.RowSelected(row,1),6}; % Retrieve UID
                Training(row) = loadTraining(UID); %#ok
            end
            
            app.Log.Value   = "Measuring consensus between trainings...";
            app.Lamp.Color  = "yellow"; pause(0.2);
            Accuracy        = zeros(numel(Training(row).Eyelid),1);
            FileNames       = {};
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Prediction pixel identities...','Value',0, 'Cancelable', 'on');
            for i = 1: numel(Training(1).Eyelid)
                [~, fName, ~] = fileparts(Training(1).Eyelid(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training(1).Eyelid)) ' : ' fName];
                dlg.Value = i/numel(Training(1).Eyelid);
                
                % Recreate the eyelid mask
                Mask = zeros(Training(1).Eyelid(i).ImgSize);
                Mask(Training(1).Eyelid(i).Vox.Ind) = 255;
                SharedEyelidPx = find(Mask);
                NumEyelidPx(i) = numel(find(Mask)); %#ok
                
                % Find the same image among all the Training (images are stored in shuffled order)
                for t = 2:numel(Training)
                    for j = 1:numel(Training(t).Eyelid)
                        if strcmp(Training(1).Eyelid(i).ImgFile, Training(t).Eyelid(j).ImgFile)
                            % Create the ground truth eyelid mask
                            Mask = zeros(Training(t).Eyelid(j).ImgSize);
                            Mask(Training(t).Eyelid(j).Vox.Ind) = 255;
                            SharedEyelidPx  = intersect(find(Mask),SharedEyelidPx);
                            NumEyelidPx(i) = mean(NumEyelidPx(i), numel(find(Mask)));
                        end
                    end
                end
                
                % Calculate accuracy based on variance across masks of the same eyelid
                Accuracy(i)  = numel(SharedEyelidPx)/NumEyelidPx(i);
                FileNames{i} = fName; %#ok
                disp(['Filename: ' fName ', Prediction accuracy:' num2str(Accuracy(i))]);
                drawnow;
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);

            FileNames = FileNames';
            T = table(FileNames, Accuracy);
            writetable(T,[pwd filesep 'validationData.xls']);
            
            app.Log.Value = "Predicting pixel identities...DONE";
            app.Lamp.Color = "green";
            
        end

        % Button pushed function: btnMergeRecords
        function btnMergeRecordsButtonPushed(app, event)
            if isempty(app.RowSelected) || size(app.RowSelected,1) < 2
                return
            end

            dlg = uiprogressdlg(app.UIFigure,'Title','Merging records...','Indeterminate', 'on');            
            
            UID       = app.tblTraining.Data{app.RowSelected(1,1),6};            
            dstT      = loadTraining(UID);
            dstT.UID  = generateUID;
            dstT.Date = datetime;
            
            for i = 2:size(app.RowSelected, 1)
                UID   = app.tblTraining.Data{app.RowSelected(i,1),6};
                srcT  = loadTraining(UID);
                                
                dstT.ImagesList = [dstT.ImagesList, srcT.ImagesList];
                dstT.ImagesTODOFollicles = [dstT.ImagesTODOFollicles, srcT.ImagesTODOFollicles];
                dstT.ImagesTODOEyelid = [dstT.ImagesTODOEyelid, srcT.ImagesTODOEyelid];
                dstT.Follicles  = [dstT.Follicles,  srcT.Follicles];
                dstT.Eyelid     = [dstT.Eyelid,     srcT.Eyelid];
            end
            
            saveTraining(dstT);
            app.RefreshTrainingTable;
            close(dlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Follicle Finder';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ManualDetectionTab = uitab(app.TabGroup);
            app.ManualDetectionTab.Title = 'Manual Detection';
            app.btnChooseUser = uibutton(app.ManualDetectionTab, 'push');
            app.btnChooseUser.ButtonPushedFcn = createCallbackFcn(app, @btnChooseUserButtonPushed, true);
            app.btnChooseUser.Icon = '48px-split.png';
            app.btnChooseUser.Tooltip = {'Change current user name'};
            app.btnChooseUser.Text = 'Choose user';
            app.btnPhotosFolder = uibutton(app.ManualDetectionTab, 'push');
            app.btnPhotosFolder.ButtonPushedFcn = createCallbackFcn(app, @btnPhotosFolderButtonPushed, true);
            app.btnPhotosFolder.Icon = '48px-Training-set.png';
            app.btnPhotosFolder.Enable = 'off';
            app.btnPhotosFolder.Tooltip = {'Open a folder containing photos to score'};
            app.btnPhotosFolder.Text = 'Photos folder';
            app.btnTagFollicles = uibutton(app.ManualDetectionTab, 'push');
            app.btnTagFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnTagFolliclesButtonPushed, true);
            app.btnTagFollicles.Icon = 'EyelidFollicles.png';
            app.btnTagFollicles.Enable = 'off';
            app.btnTagFollicles.Tooltip = {'Manually identify follicles'};
            app.btnTagFollicles.Text = 'Paint Follicles';
            app.btnTraceEyelid = uibutton(app.ManualDetectionTab, 'push');
            app.btnTraceEyelid.ButtonPushedFcn = createCallbackFcn(app, @btnTraceEyelidButtonPushed, true);
            app.btnTraceEyelid.Icon = 'Eyelid.png';
            app.btnTraceEyelid.Enable = 'off';
            app.btnTraceEyelid.Tooltip = {'Manually identify the eyelid region'};
            app.btnTraceEyelid.Text = 'Trace Eyelid';
            app.btnDeleteRow = uibutton(app.ManualDetectionTab, 'push');
            app.btnDeleteRow.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteRowButtonPushed, true);
            app.btnDeleteRow.Icon = '48px-Delete.png';
            app.btnDeleteRow.Enable = 'off';
            app.btnDeleteRow.Tooltip = {'Remove selected row'};
            app.btnDeleteRow.Text = 'Delete';
            app.btnExportTraining = uibutton(app.ManualDetectionTab, 'push');
            app.btnExportTraining.ButtonPushedFcn = createCallbackFcn(app, @btnExportTrainingButtonPushed, true);
            app.btnExportTraining.Icon = '48px-export.png';
            app.btnExportTraining.Enable = 'off';
            app.btnExportTraining.Tooltip = {'Save a copy of curretnt training to a custom folder'};
            app.btnExportTraining.Text = 'Export';
            app.btnImportTraining = uibutton(app.ManualDetectionTab, 'push');
            app.btnImportTraining.ButtonPushedFcn = createCallbackFcn(app, @btnImportTrainingButtonPushed, true);
            app.btnImportTraining.Icon = '48px-import.png';
            app.btnImportTraining.Tooltip = {'Import saved training into the local repository'};
            app.btnImportTraining.Text = 'Import';
            app.AutoDetectionTab = uitab(app.TabGroup);
            app.AutoDetectionTab.Title = 'Auto Detection';
            app.btnAutoTagFollicles = uibutton(app.AutoDetectionTab, 'push');
            app.btnAutoTagFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnAutoTagFolliclesButtonPushed, true);
            app.btnAutoTagFollicles.Icon = 'AutoFollicles.png';
            app.btnAutoTagFollicles.Enable = 'off';
            app.btnAutoTagFollicles.Tooltip = {'Automatically identify follicles'};
            app.btnAutoTagFollicles.Text = 'Detect Follicles';
            app.btnAutoChooseUser = uibutton(app.AutoDetectionTab, 'push');
            app.btnAutoChooseUser.ButtonPushedFcn = createCallbackFcn(app, @btnChooseUserButtonPushed, true);
            app.btnAutoChooseUser.Icon = '48px-split.png';
            app.btnAutoChooseUser.Tooltip = {'Change current user name'};
            app.btnAutoChooseUser.Text = 'Choose user';
            app.btnAutoPhotosFolder = uibutton(app.AutoDetectionTab, 'push');
            app.btnAutoPhotosFolder.ButtonPushedFcn = createCallbackFcn(app, @btnPhotosFolderButtonPushed, true);
            app.btnAutoPhotosFolder.Icon = '48px-Training-set.png';
            app.btnAutoPhotosFolder.Enable = 'off';
            app.btnAutoPhotosFolder.Tooltip = {'Open a folder containing photos to score'};
            app.btnAutoPhotosFolder.Text = 'Photos folder';
            app.MinsizeSpinnerLabel = uilabel(app.AutoDetectionTab);
            app.MinsizeSpinnerLabel.Text = 'Min size:';
            app.cmbMinSize = uispinner(app.AutoDetectionTab);
            app.cmbMinSize.Limits = [0 Inf];
            app.cmbMinSize.Tooltip = {'Size of the smallest expected object'};
            app.cmbMinSize.Value = 90;
            app.MaxsizeSpinnerLabel = uilabel(app.AutoDetectionTab);
            app.MaxsizeSpinnerLabel.Text = 'Max size:';
            app.cmbMaxSize = uispinner(app.AutoDetectionTab);
            app.cmbMaxSize.Limits = [0 Inf];
            app.cmbMaxSize.Tooltip = {'Size of the biggest expected object'};
            app.cmbMaxSize.Value = 150;
            app.chkWatershed = uicheckbox(app.AutoDetectionTab);
            app.chkWatershed.Tooltip = {'Split partially overlapping objects'};
            app.chkWatershed.Text = 'Watershed segmentation';
            app.MinSNRSpinnerLabel = uilabel(app.AutoDetectionTab);
            app.MinSNRSpinnerLabel.Text = 'Min SNR:';
            app.cmbMinSNR = uispinner(app.AutoDetectionTab);
            app.cmbMinSNR.Limits = [1 Inf];
            app.cmbMinSNR.Tooltip = {'Dimmest object intensity (# times image background level)'};
            app.cmbMinSNR.Value = 2.2;
            app.btnValidateFollicles = uibutton(app.AutoDetectionTab, 'push');
            app.btnValidateFollicles.Icon = '48px-ValidateFollicles.png';
            app.btnValidateFollicles.Enable = 'off';
            app.btnValidateFollicles.Tooltip = {'Validate follicle candidates'};
            app.btnValidateFollicles.Text = 'Validate Follicles';
            app.BackgroundlevelestimatorLabel = uilabel(app.AutoDetectionTab);
            app.BackgroundlevelestimatorLabel.Text = 'Background level estimator';
            app.cmbNoiseEstimator = uidropdown(app.AutoDetectionTab);
            app.cmbNoiseEstimator.Items = {'Standard deviation', 'Most common intensity', 'Minimum intensity'};
            app.cmbNoiseEstimator.Value = 'Standard deviation';
            app.NeuralNetTab = uitab(app.TabGroup);
            app.NeuralNetTab.Title = 'NeuralNet';
            app.btnNetGTruth = uibutton(app.NeuralNetTab, 'push');
            app.btnNetGTruth.ButtonPushedFcn = createCallbackFcn(app, @btnNetGTruthButtonPushed, true);
            app.btnNetGTruth.Icon = '48px-Training-set.png';
            app.btnNetGTruth.Enable = 'off';
            app.btnNetGTruth.Tooltip = {'Generate a ground truth dataset from selected Training'};
            app.btnNetGTruth.Text = {'Generate'; 'Training Set'};
            app.btnNetTrain = uibutton(app.NeuralNetTab, 'push');
            app.btnNetTrain.ButtonPushedFcn = createCallbackFcn(app, @btnNetTrainButtonPushed, true);
            app.btnNetTrain.Icon = '48px-monkey.png';
            app.btnNetTrain.Enable = 'off';
            app.btnNetTrain.Tooltip = {'Train the selected NeuralNet'};
            app.btnNetTrain.Text = {'Train'; 'Network'};
            app.btnNetTest = uibutton(app.NeuralNetTab, 'push');
            app.btnNetTest.ButtonPushedFcn = createCallbackFcn(app, @btnNetTestButtonPushed, true);
            app.btnNetTest.Icon = '48px-unblock-granted.png';
            app.btnNetTest.Enable = 'off';
            app.btnNetTest.Tooltip = {'Test the selected trained network'};
            app.btnNetTest.Text = {'Test'; 'Network'};
            app.NetNameEditFieldLabel = uilabel(app.NeuralNetTab);
            app.NetNameEditFieldLabel.Text = 'Net Name:';
            app.txtNetName = uieditfield(app.NeuralNetTab, 'text');
            app.txtNetName.ValueChangedFcn = createCallbackFcn(app, @txtNetNameValueChanged, true);
            app.NetTypeDropDownLabel = uilabel(app.NeuralNetTab);
            app.NetTypeDropDownLabel.Text = 'Net Type:';
            app.cmbNetType = uidropdown(app.NeuralNetTab);
            app.cmbNetType.Items = {'Pixel classifier', 'Object classifier', 'Object detector'};
            app.cmbNetType.Value = 'Pixel classifier';
            app.NetModelDropDownLabel = uilabel(app.NeuralNetTab);
            app.NetModelDropDownLabel.Text = 'Net Model:';
            app.cmbNetModel = uidropdown(app.NeuralNetTab);
            app.cmbNetModel.Items = {'Nico', 'YOLOv2', 'Alexnet', 'VGG-16'};
            app.cmbNetModel.Value = 'Nico';
            app.btnNetCreate = uibutton(app.NeuralNetTab, 'push');
            app.btnNetCreate.ButtonPushedFcn = createCallbackFcn(app, @btnNetCreateButtonPushed, true);
            app.btnNetCreate.Icon = '48px-new.png';
            app.btnNetCreate.Tooltip = {'Create an ew NeuralNet of the specified model'};
            app.btnNetCreate.Text = {'Create'; 'Network'};
            app.btnNetDelete = uibutton(app.NeuralNetTab, 'push');
            app.btnNetDelete.ButtonPushedFcn = createCallbackFcn(app, @btnNetDeleteButtonPushed, true);
            app.btnNetDelete.Icon = '48px-Delete.png';
            app.btnNetDelete.Enable = 'off';
            app.btnNetDelete.Tooltip = {'Delete selected NeuralNey'};
            app.btnNetDelete.Text = '';
            app.btnNetClone = uibutton(app.NeuralNetTab, 'push');
            app.btnNetClone.ButtonPushedFcn = createCallbackFcn(app, @btnNetCloneButtonPushed, true);
            app.btnNetClone.Icon = '48px-copy.png';
            app.btnNetClone.Enable = 'off';
            app.btnNetClone.Tooltip = {'Duplicate selected NeuralNet'};
            app.btnNetClone.Text = '';
            app.btnNetDesign = uibutton(app.NeuralNetTab, 'push');
            app.btnNetDesign.ButtonPushedFcn = createCallbackFcn(app, @btnNetDesignButtonPushed, true);
            app.btnNetDesign.Icon = '48px-Design.png';
            app.btnNetDesign.Tooltip = {'Open Deep Network Designer'};
            app.btnNetDesign.Text = '';
            app.btnNetInfo = uibutton(app.NeuralNetTab, 'push');
            app.btnNetInfo.ButtonPushedFcn = createCallbackFcn(app, @btnNetInfoButtonPushed, true);
            app.btnNetInfo.Icon = '48px-info.png';
            app.btnNetInfo.Enable = 'off';
            app.btnNetInfo.Tooltip = {'Visualize NeuralNet architecture'};
            app.btnNetInfo.Text = '';
            app.btnNetImport = uibutton(app.NeuralNetTab, 'push');
            app.btnNetImport.ButtonPushedFcn = createCallbackFcn(app, @btnNetImportButtonPushed, true);
            app.btnNetImport.Icon = '48px-import.png';
            app.btnNetImport.Tooltip = {'Import NeuralNet layers from workspace'};
            app.btnNetImport.Text = '';
            app.btnNetExport = uibutton(app.NeuralNetTab, 'push');
            app.btnNetExport.ButtonPushedFcn = createCallbackFcn(app, @btnNetExportButtonPushed, true);
            app.btnNetExport.Icon = '48px-export.png';
            app.btnNetExport.Enable = 'off';
            app.btnNetExport.Tooltip = {'Save a copy of selected NeuralNet to custom folder'};
            app.btnNetExport.Text = '';
            app.CompareTab = uitab(app.TabGroup);
            app.CompareTab.Title = 'Compare';
            app.btnCompareEyelid = uibutton(app.CompareTab, 'push');
            app.btnCompareEyelid.ButtonPushedFcn = createCallbackFcn(app, @btnCompareEyelidButtonPushed, true);
            app.btnCompareEyelid.Icon = 'Eyelid.png';
            app.btnCompareEyelid.Tooltip = {'Resize all images in selected folder'};
            app.btnCompareEyelid.Text = {'Eyelid '; 'Records'};
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.btnBatchResize = uibutton(app.ToolsTab, 'push');
            app.btnBatchResize.ButtonPushedFcn = createCallbackFcn(app, @btnBatchResizeButtonPushed, true);
            app.btnBatchResize.Icon = '48px-resize.png';
            app.btnBatchResize.Tooltip = {'Resize all images in selected folder'};
            app.btnBatchResize.Text = {'Batch Resize'; 'Images'};
            app.btnBatchMaskEyelid = uibutton(app.ToolsTab, 'push');
            app.btnBatchMaskEyelid.ButtonPushedFcn = createCallbackFcn(app, @btnBatchMaskEyelidButtonPushed, true);
            app.btnBatchMaskEyelid.Icon = '48px-Masks.png';
            app.btnBatchMaskEyelid.Enable = 'off';
            app.btnBatchMaskEyelid.Tooltip = {'Create binary mask image files of eyelids'};
            app.btnBatchMaskEyelid.Text = {'Create Eyelid'; 'Masks'};
            app.btnBatchSaveEachFollicle = uibutton(app.ToolsTab, 'push');
            app.btnBatchSaveEachFollicle.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveEachFollicleButtonPushed, true);
            app.btnBatchSaveEachFollicle.Icon = '48px-Mushroom-cutout.png';
            app.btnBatchSaveEachFollicle.Enable = 'off';
            app.btnBatchSaveEachFollicle.Tooltip = {'Create image cutouts of each follicle'};
            app.btnBatchSaveEachFollicle.Text = {'Save Individual '; 'Follicles'};
            app.btnBatchMaskFollicles = uibutton(app.ToolsTab, 'push');
            app.btnBatchMaskFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnBatchMaskFolliclesButtonPushed, true);
            app.btnBatchMaskFollicles.Icon = '48px-Mushroom.png';
            app.btnBatchMaskFollicles.Enable = 'off';
            app.btnBatchMaskFollicles.Tooltip = {'Create binary mask image files of follicles'};
            app.btnBatchMaskFollicles.Text = {'Create Follicle '; 'Masks'};
            app.btnRelinkImages = uibutton(app.ToolsTab, 'push');
            app.btnRelinkImages.ButtonPushedFcn = createCallbackFcn(app, @btnRelinkImagesButtonPushed, true);
            app.btnRelinkImages.Icon = '48px-relink-images.png';
            app.btnRelinkImages.Enable = 'off';
            app.btnRelinkImages.Tooltip = {'Relink image folder to a new location'};
            app.btnRelinkImages.Text = {'Relink Image'; 'Folder'};
            app.btnBatchSaveBboxes = uibutton(app.ToolsTab, 'push');
            app.btnBatchSaveBboxes.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveBboxesButtonPushed, true);
            app.btnBatchSaveBboxes.Icon = '48px-Mushroom-bboxes.png';
            app.btnBatchSaveBboxes.Enable = 'off';
            app.btnBatchSaveBboxes.Tooltip = {'Save bounding boxes coordinates into excel tables'};
            app.btnBatchSaveBboxes.Text = {'Save Follicles '; 'Bounding Boxes'};
            app.btnMergeRecords = uibutton(app.ToolsTab, 'push');
            app.btnMergeRecords.ButtonPushedFcn = createCallbackFcn(app, @btnMergeRecordsButtonPushed, true);
            app.btnMergeRecords.Icon = '48px-Merge.png';
            app.btnMergeRecords.Enable = 'off';
            app.btnMergeRecords.Tooltip = {'Merge selected records into a single one'};
            app.btnMergeRecords.Text = {'Merge'; 'Records'};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ZoomlevelPanel = uipanel(app.SettingsTab);
            app.ZoomlevelPanel.Title = 'Zoom level';
            app.FolliclesLabel = uilabel(app.ZoomlevelPanel);
            app.FolliclesLabel.Text = 'Follicles';
            app.cmbZoomFollicles = uispinner(app.ZoomlevelPanel);
            app.cmbZoomFollicles.Limits = [1 32];
            app.cmbZoomFollicles.ValueChangedFcn = createCallbackFcn(app, @cmbZoomFolliclesValueChanged, true);
            app.cmbZoomFollicles.Value = 2;
            app.EyelidLabel = uilabel(app.ZoomlevelPanel);
            app.EyelidLabel.Text = 'Eyelid';
            app.cmbZoomEyelid = uispinner(app.ZoomlevelPanel);
            app.cmbZoomEyelid.Limits = [1 32];
            app.cmbZoomEyelid.ValueChangedFcn = createCallbackFcn(app, @cmbZoomEyelidValueChanged, true);
            app.cmbZoomEyelid.Value = 1;
            app.ObjectsColorPanel = uipanel(app.SettingsTab);
            app.ObjectsColorPanel.Title = 'Objects Color';
            app.btnColorSelected = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorSelected.ButtonPushedFcn = createCallbackFcn(app, @btnColorSelectedButtonPushed, true);
            app.btnColorSelected.Text = 'Selected';
            app.btnColorFalse = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorFalse.ButtonPushedFcn = createCallbackFcn(app, @btnColorFalseButtonPushed, true);
            app.btnColorFalse.Text = 'False';
            app.btnColorTrue = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorTrue.ButtonPushedFcn = createCallbackFcn(app, @btnColorTrueButtonPushed, true);
            app.btnColorTrue.Text = 'True';
            app.btnColorMaybe = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorMaybe.ButtonPushedFcn = createCallbackFcn(app, @btnColorMaybeButtonPushed, true);
            app.btnColorMaybe.Text = 'Maybe';
            app.ColorFalse = uilamp(app.ObjectsColorPanel);
            app.ColorSelected = uilamp(app.ObjectsColorPanel);
            app.ColorTrue = uilamp(app.ObjectsColorPanel);
            app.ColorMaybe = uilamp(app.ObjectsColorPanel);
            app.btnRefreshTable_2 = uibutton(app.SettingsTab, 'push');
            app.btnRefreshTable_2.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshTable_2ButtonPushed, true);
            app.btnRefreshTable_2.Icon = '48px-Refresh.png';
            app.btnRefreshTable_2.Tooltip = {'Refresh table of analyzed data'};
            app.btnRefreshTable_2.Text = 'Reset Defaults';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'app-icon2.svg';
            app.FollicleFinderLabel = uilabel(app.AboutTab);
            app.FollicleFinderLabel.Text = 'Follicle Finder';
            app.Version20beta1Label = uilabel(app.AboutTab);
            app.Version20beta1Label.Text = 'Version 2.0 beta 1';
            app.Copyright2019LucaDellaSantinaLabel = uilabel(app.AboutTab);
            app.Copyright2019LucaDellaSantinaLabel.Text = 'Copyright 2019 Luca Della Santina';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'GNU GENERAL PUBLIC LICENSE'; 'Version 3'; ' 29 June 2007'; 'Copyright © 2007 Free Software Foundation'; ' Inc. <https://fsf.org/>'; 'Everyone is permitted to copy and distribute verbatim copies of this license document but changing it is not allowed.'; ''; 'Preamble'; 'The GNU General Public License is a free copyleft license for software and other kinds of works.'; 'The licenses for most software and other practical works are designed to take away your freedom to share and change the works. By contrast the GNU General Public License is intended to guarantee your freedom to share and change all versions of a program--to make sure it remains free software for all its users. We the Free Software Foundation use the GNU General Public License for most of our software it applies also to any other work released this way by its authors. You can apply it to your programs too.'; 'When we speak of free software we are referring to freedom not price. Our General Public Licenses are designed to make sure that you have the freedom to distribute copies of free software (and charge for them if you wish) that you receive source code or can get it if you want it that you can change the software or use pieces of it in new free programs and that you know you can do these things.'; 'To protect your rights we need to prevent others from denying you these rights or asking you to surrender the rights. Therefore'; ' you have certain responsibilities if you distribute copies of the software or if you modify it: responsibilities to respect the freedom of others.'; 'For example if you distribute copies of such a program'; ' whether gratis or for a fee you must pass on to the recipients the same freedoms that you received. You must make sure that they'; ' too receive or can get the source code. And you must show them these terms so they know their rights.'; 'Developers that use the GNU GPL protect your rights with two steps: (1) assert copyright on the software and (2) offer you this License giving you legal permission to copy distribute and/or modify it.'; 'For the developers'' and authors'' protection the GPL clearly explains that there is no warranty for this free software. For both users'' and authors'' sake'; ' the GPL requires that modified versions be marked as changed so that their problems will not be attributed erroneously to authors of previous versions.'; 'Some devices are designed to deny users access to install or run modified versions of the software inside them although the manufacturer can do so. This is fundamentally incompatible with the aim of protecting users'' freedom to change the software. The systematic pattern of such abuse occurs in the area of products for individuals to use'; ' which is precisely where it is most unacceptable. Therefore we have designed this version of the GPL to prohibit the practice for those products. If such problems arise substantially in other domains'; ' we stand ready to extend this provision to those domains in future versions of the GPL as needed to protect the freedom of users.'; 'Finally every program is threatened constantly by software patents. States should not allow patents to restrict development and use of software on general-purpose computers but in those that do we wish to avoid the special danger that patents applied to a free program could make it effectively proprietary. To prevent this the GPL assures that patents cannot be used to render the program non-free.'; 'The precise terms and conditions for copying distribution and modification follow.'; ''; 'TERMS AND CONDITIONS'; '0. Definitions.'; '“This License” refers to version 3 of the GNU General Public License.'; '“Copyright” also means copyright-like laws that apply to other kinds of works'; ' such as semiconductor masks.'; '“The Program” refers to any copyrightable work licensed under this License. Each licensee is addressed as “you”. “Licensees” and “recipients” may be individuals or organizations.'; 'To “modify” a work means to copy from or adapt all or part of the work in a fashion requiring copyright permission'; ' other than the making of an exact copy. The resulting work is called a “modified version” of the earlier work or a work “based on” the earlier work.'; 'A “covered work” means either the unmodified Program or a work based on the Program.'; 'To “propagate” a work means to do anything with it that'; ' without permission'; ' would make you directly or secondarily liable for infringement under applicable copyright law'; ' except executing it on a computer or modifying a private copy. Propagation includes copying'; ' distribution (with or without modification)'; ' making available to the public'; ' and in some countries other activities as well.'; 'To “convey” a work means any kind of propagation that enables other parties to make or receive copies. Mere interaction with a user through a computer network'; ' with no transfer of a copy'; ' is not conveying.'; 'An interactive user interface displays “Appropriate Legal Notices” to the extent that it includes a convenient and prominently visible feature that (1) displays an appropriate copyright notice'; ' and (2) tells the user that there is no warranty for the work (except to the extent that warranties are provided)'; ' that licensees may convey the work under this License'; ' and how to view a copy of this License. If the interface presents a list of user commands or options'; ' such as a menu'; ' a prominent item in the list meets this criterion.'; '1. Source Code.'; 'The “source code” for a work means the preferred form of the work for making modifications to it. “Object code” means any non-source form of a work.'; 'A “Standard Interface” means an interface that either is an official standard defined by a recognized standards body'; ' or'; ' in the case of interfaces specified for a particular programming language'; ' one that is widely used among developers working in that language.'; 'The “System Libraries” of an executable work include anything'; ' other than the work as a whole'; ' that (a) is included in the normal form of packaging a Major Component'; ' but which is not part of that Major Component'; ' and (b) serves only to enable use of the work with that Major Component'; ' or to implement a Standard Interface for which an implementation is available to the public in source code form. A “Major Component”'; ' in this context'; ' means a major essential component (kernel'; ' window system'; ' and so on) of the specific operating system (if any) on which the executable work runs'; ' or a compiler used to produce the work'; ' or an object code interpreter used to run it.'; 'The “Corresponding Source” for a work in object code form means all the source code needed to generate'; ' install'; ' and (for an executable work) run the object code and to modify the work'; ' including scripts to control those activities. However'; ' it does not include the work''s System Libraries'; ' or general-purpose tools or generally available free programs which are used unmodified in performing those activities but which are not part of the work. For example'; ' Corresponding Source includes interface definition files associated with source files for the work'; ' and the source code for shared libraries and dynamically linked subprograms that the work is specifically designed to require'; ' such as by intimate data communication or control flow between those subprograms and other parts of the work.'; 'The Corresponding Source need not include anything that users can regenerate automatically from other parts of the Corresponding Source.'; 'The Corresponding Source for a work in source code form is that same work.'; '2. Basic Permissions.'; 'All rights granted under this License are granted for the term of copyright on the Program'; ' and are irrevocable provided the stated conditions are met. This License explicitly affirms your unlimited permission to run the unmodified Program. The output from running a covered work is covered by this License only if the output'; ' given its content'; ' constitutes a covered work. This License acknowledges your rights of fair use or other equivalent'; ' as provided by copyright law.'; 'You may make'; ' run and propagate covered works that you do not convey'; ' without conditions so long as your license otherwise remains in force. You may convey covered works to others for the sole purpose of having them make modifications exclusively for you'; ' or provide you with facilities for running those works'; ' provided that you comply with the terms of this License in conveying all material for which you do not control copyright. Those thus making or running the covered works for you must do so exclusively on your behalf'; ' under your direction and control'; ' on terms that prohibit them from making any copies of your copyrighted material outside their relationship with you.'; 'Conveying under any other circumstances is permitted solely under the conditions stated below. Sublicensing is not allowed'; ' section 10 makes it unnecessary.'; '3. Protecting Users'' Legal Rights From Anti-Circumvention Law.'; 'No covered work shall be deemed part of an effective technological measure under any applicable law fulfilling obligations under article 11 of the WIPO copyright treaty adopted on 20 December 1996'; ' or similar laws prohibiting or restricting circumvention of such measures.'; 'When you convey a covered work'; ' you waive any legal power to forbid circumvention of technological measures to the extent such circumvention is effected by exercising rights under this License with respect to the covered work'; ' and you disclaim any intention to limit operation or modification of the work as a means of enforcing'; ' against the work''s users'; ' your or third parties'' legal rights to forbid circumvention of technological measures.'; '4. Conveying Verbatim Copies.'; 'You may convey verbatim copies of the Program''s source code as you receive it'; ' in any medium'; ' provided that you conspicuously and appropriately publish on each copy an appropriate copyright notice'; ' keep intact all notices stating that this License and any non-permissive terms added in accord with section 7 apply to the code'; ' keep intact all notices of the absence of any warranty'; ' and give all recipients a copy of this License along with the Program.'; 'You may charge any price or no price for each copy that you convey'; ' and you may offer support or warranty protection for a fee.'; '5. Conveying Modified Source Versions.'; 'You may convey a work based on the Program'; ' or the modifications to produce it from the Program'; ' in the form of source code under the terms of section 4'; ' provided that you also meet all of these conditions:'; '•	a) The work must carry prominent notices stating that you modified it'; ' and giving a relevant date.'; '•	b) The work must carry prominent notices stating that it is released under this License and any conditions added under section 7. This requirement modifies the requirement in section 4 to “keep intact all notices”.'; '•	c) You must license the entire work'; ' as a whole'; ' under this License to anyone who comes into possession of a copy. This License will therefore apply'; ' along with any applicable section 7 additional terms'; ' to the whole of the work'; ' and all its parts'; ' regardless of how they are packaged. This License gives no permission to license the work in any other way'; ' but it does not invalidate such permission if you have separately received it.'; '•	d) If the work has interactive user interfaces'; ' each must display Appropriate Legal Notices'; ' however'; ' if the Program has interactive interfaces that do not display Appropriate Legal Notices'; ' your work need not make them do so.'; 'A compilation of a covered work with other separate and independent works'; ' which are not by their nature extensions of the covered work'; ' and which are not combined with it such as to form a larger program'; ' in or on a volume of a storage or distribution medium'; ' is called an “aggregate” if the compilation and its resulting copyright are not used to limit the access or legal rights of the compilation''s users beyond what the individual works permit. Inclusion of a covered work in an aggregate does not cause this License to apply to the other parts of the aggregate.'; '6. Conveying Non-Source Forms.'; 'You may convey a covered work in object code form under the terms of sections 4 and 5'; ' provided that you also convey the machine-readable Corresponding Source under the terms of this License'; ' in one of these ways:'; '•	a) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by the Corresponding Source fixed on a durable physical medium customarily used for software interchange.'; '•	b) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by a written offer'; ' valid for at least three years and valid for as long as you offer spare parts or customer support for that product model'; ' to give anyone who possesses the object code either (1) a copy of the Corresponding Source for all the software in the product that is covered by this License'; ' on a durable physical medium customarily used for software interchange'; ' for a price no more than your reasonable cost of physically performing this conveying of source'; ' or (2) access to copy the Corresponding Source from a network server at no charge.'; '•	c) Convey individual copies of the object code with a copy of the written offer to provide the Corresponding Source. This alternative is allowed only occasionally and noncommercially'; ' and only if you received the object code with such an offer'; ' in accord with subsection 6b.'; '•	d) Convey the object code by offering access from a designated place (gratis or for a charge)'; ' and offer equivalent access to the Corresponding Source in the same way through the same place at no further charge. You need not require recipients to copy the Corresponding Source along with the object code. If the place to copy the object code is a network server'; ' the Corresponding Source may be on a different server (operated by you or a third party) that supports equivalent copying facilities'; ' provided you maintain clear directions next to the object code saying where to find the Corresponding Source. Regardless of what server hosts the Corresponding Source'; ' you remain obligated to ensure that it is available for as long as needed to satisfy these requirements.'; '•	e) Convey the object code using peer-to-peer transmission'; ' provided you inform other peers where the object code and Corresponding Source of the work are being offered to the general public at no charge under subsection 6d.'; 'A separable portion of the object code'; ' whose source code is excluded from the Corresponding Source as a System Library'; ' need not be included in conveying the object code work.'; 'A “User Product” is either (1) a “consumer product”'; ' which means any tangible personal property which is normally used for personal'; ' family'; ' or household purposes'; ' or (2) anything designed or sold for incorporation into a dwelling. In determining whether a product is a consumer product'; ' doubtful cases shall be resolved in favor of coverage. For a particular product received by a particular user'; ' “normally used” refers to a typical or common use of that class of product'; ' regardless of the status of the particular user or of the way in which the particular user actually uses'; ' or expects or is expected to use'; ' the product. A product is a consumer product regardless of whether the product has substantial commercial'; ' industrial or non-consumer uses'; ' unless such uses represent the only significant mode of use of the product.'; '“Installation Information” for a User Product means any methods'; ' procedures'; ' authorization keys'; ' or other information required to install and execute modified versions of a covered work in that User Product from a modified version of its Corresponding Source. The information must suffice to ensure that the continued functioning of the modified object code is in no case prevented or interfered with solely because modification has been made.'; 'If you convey an object code work under this section in'; ' or with'; ' or specifically for use in'; ' a User Product'; ' and the conveying occurs as part of a transaction in which the right of possession and use of the User Product is transferred to the recipient in perpetuity or for a fixed term (regardless of how the transaction is characterized)'; ' the Corresponding Source conveyed under this section must be accompanied by the Installation Information. But this requirement does not apply if neither you nor any third party retains the ability to install modified object code on the User Product (for example'; ' the work has been installed in ROM).'; 'The requirement to provide Installation Information does not include a requirement to continue to provide support service'; ' warranty'; ' or updates for a work that has been modified or installed by the recipient'; ' or for the User Product in which it has been modified or installed. Access to a network may be denied when the modification itself materially and adversely affects the operation of the network or violates the rules and protocols for communication across the network.'; 'Corresponding Source conveyed'; ' and Installation Information provided'; ' in accord with this section must be in a format that is publicly documented (and with an implementation available to the public in source code form)'; ' and must require no special password or key for unpacking'; ' reading or copying.'; '7. Additional Terms.'; '“Additional permissions” are terms that supplement the terms of this License by making exceptions from one or more of its conditions. Additional permissions that are applicable to the entire Program shall be treated as though they were included in this License'; ' to the extent that they are valid under applicable law. If additional permissions apply only to part of the Program'; ' that part may be used separately under those permissions'; ' but the entire Program remains governed by this License without regard to the additional permissions.'; 'When you convey a copy of a covered work'; ' you may at your option remove any additional permissions from that copy'; ' or from any part of it. (Additional permissions may be written to require their own removal in certain cases when you modify the work.) You may place additional permissions on material'; ' added by you to a covered work'; ' for which you have or can give appropriate copyright permission.'; 'Notwithstanding any other provision of this License'; ' for material you add to a covered work'; ' you may (if authorized by the copyright holders of that material) supplement the terms of this License with terms:'; '•	a) Disclaiming warranty or limiting liability differently from the terms of sections 15 and 16 of this License'; ' or'; '•	b) Requiring preservation of specified reasonable legal notices or author attributions in that material or in the Appropriate Legal Notices displayed by works containing it'; ' or'; '•	c) Prohibiting misrepresentation of the origin of that material'; ' or requiring that modified versions of such material be marked in reasonable ways as different from the original version'; ' or'; '•	d) Limiting the use for publicity purposes of names of licensors or authors of the material'; ' or'; '•	e) Declining to grant rights under trademark law for use of some trade names'; ' trademarks'; ' or service marks'; ' or'; '•	f) Requiring indemnification of licensors and authors of that material by anyone who conveys the material (or modified versions of it) with contractual assumptions of liability to the recipient'; ' for any liability that these contractual assumptions directly impose on those licensors and authors.'; 'All other non-permissive additional terms are considered “further restrictions” within the meaning of section 10. If the Program as you received it'; ' or any part of it'; ' contains a notice stating that it is governed by this License along with a term that is a further restriction'; ' you may remove that term. If a license document contains a further restriction but permits relicensing or conveying under this License'; ' you may add to a covered work material governed by the terms of that license document'; ' provided that the further restriction does not survive such relicensing or conveying.'; 'If you add terms to a covered work in accord with this section'; ' you must place'; ' in the relevant source files'; ' a statement of the additional terms that apply to those files'; ' or a notice indicating where to find the applicable terms.'; 'Additional terms'; ' permissive or non-permissive'; ' may be stated in the form of a separately written license'; ' or stated as exceptions'; ' the above requirements apply either way.'; '8. Termination.'; 'You may not propagate or modify a covered work except as expressly provided under this License. Any attempt otherwise to propagate or modify it is void'; ' and will automatically terminate your rights under this License (including any patent licenses granted under the third paragraph of section 11).'; 'However'; ' if you cease all violation of this License'; ' then your license from a particular copyright holder is reinstated (a) provisionally'; ' unless and until the copyright holder explicitly and finally terminates your license'; ' and (b) permanently'; ' if the copyright holder fails to notify you of the violation by some reasonable means prior to 60 days after the cessation.'; 'Moreover'; ' your license from a particular copyright holder is reinstated permanently if the copyright holder notifies you of the violation by some reasonable means'; ' this is the first time you have received notice of violation of this License (for any work) from that copyright holder'; ' and you cure the violation prior to 30 days after your receipt of the notice.'; 'Termination of your rights under this section does not terminate the licenses of parties who have received copies or rights from you under this License. If your rights have been terminated and not permanently reinstated'; ' you do not qualify to receive new licenses for the same material under section 10.'; '9. Acceptance Not Required for Having Copies.'; 'You are not required to accept this License in order to receive or run a copy of the Program. Ancillary propagation of a covered work occurring solely as a consequence of using peer-to-peer transmission to receive a copy likewise does not require acceptance. However'; ' nothing other than this License grants you permission to propagate or modify any covered work. These actions infringe copyright if you do not accept this License. Therefore'; ' by modifying or propagating a covered work'; ' you indicate your acceptance of this License to do so.'; '10. Automatic Licensing of Downstream Recipients.'; 'Each time you convey a covered work'; ' the recipient automatically receives a license from the original licensors'; ' to run'; ' modify and propagate that work'; ' subject to this License. You are not responsible for enforcing compliance by third parties with this License.'; 'An “entity transaction” is a transaction transferring control of an organization'; ' or substantially all assets of one'; ' or subdividing an organization'; ' or merging organizations. If propagation of a covered work results from an entity transaction'; ' each party to that transaction who receives a copy of the work also receives whatever licenses to the work the party''s predecessor in interest had or could give under the previous paragraph'; ' plus a right to possession of the Corresponding Source of the work from the predecessor in interest'; ' if the predecessor has it or can get it with reasonable efforts.'; 'You may not impose any further restrictions on the exercise of the rights granted or affirmed under this License. For example'; ' you may not impose a license fee'; ' royalty'; ' or other charge for exercise of rights granted under this License'; ' and you may not initiate litigation (including a cross-claim or counterclaim in a lawsuit) alleging that any patent claim is infringed by making'; ' using'; ' selling'; ' offering for sale'; ' or importing the Program or any portion of it.'; '11. Patents.'; 'A “contributor” is a copyright holder who authorizes use under this License of the Program or a work on which the Program is based. The work thus licensed is called the contributor''s “contributor version”.'; 'A contributor''s “essential patent claims” are all patent claims owned or controlled by the contributor'; ' whether already acquired or hereafter acquired'; ' that would be infringed by some manner'; ' permitted by this License'; ' of making'; ' using'; ' or selling its contributor version'; ' but do not include claims that would be infringed only as a consequence of further modification of the contributor version. For purposes of this definition'; ' “control” includes the right to grant patent sublicenses in a manner consistent with the requirements of this License.'; 'Each contributor grants you a non-exclusive'; ' worldwide'; ' royalty-free patent license under the contributor''s essential patent claims'; ' to make'; ' use'; ' sell'; ' offer for sale'; ' import and otherwise run'; ' modify and propagate the contents of its contributor version.'; 'In the following three paragraphs'; ' a “patent license” is any express agreement or commitment'; ' however denominated'; ' not to enforce a patent (such as an express permission to practice a patent or covenant not to sue for patent infringement). To “grant” such a patent license to a party means to make such an agreement or commitment not to enforce a patent against the party.'; 'If you convey a covered work'; ' knowingly relying on a patent license'; ' and the Corresponding Source of the work is not available for anyone to copy'; ' free of charge and under the terms of this License'; ' through a publicly available network server or other readily accessible means'; ' then you must either (1) cause the Corresponding Source to be so available'; ' or (2) arrange to deprive yourself of the benefit of the patent license for this particular work'; ' or (3) arrange'; ' in a manner consistent with the requirements of this License'; ' to extend the patent license to downstream recipients. “Knowingly relying” means you have actual knowledge that'; ' but for the patent license'; ' your conveying the covered work in a country'; ' or your recipient''s use of the covered work in a country'; ' would infringe one or more identifiable patents in that country that you have reason to believe are valid.'; 'If'; ' pursuant to or in connection with a single transaction or arrangement'; ' you convey'; ' or propagate by procuring conveyance of'; ' a covered work'; ' and grant a patent license to some of the parties receiving the covered work authorizing them to use'; ' propagate'; ' modify or convey a specific copy of the covered work'; ' then the patent license you grant is automatically extended to all recipients of the covered work and works based on it.'; 'A patent license is “discriminatory” if it does not include within the scope of its coverage'; ' prohibits the exercise of'; ' or is conditioned on the non-exercise of one or more of the rights that are specifically granted under this License. You may not convey a covered work if you are a party to an arrangement with a third party that is in the business of distributing software'; ' under which you make payment to the third party based on the extent of your activity of conveying the work'; ' and under which the third party grants'; ' to any of the parties who would receive the covered work from you'; ' a discriminatory patent license (a) in connection with copies of the covered work conveyed by you (or copies made from those copies)'; ' or (b) primarily for and in connection with specific products or compilations that contain the covered work'; ' unless you entered into that arrangement'; ' or that patent license was granted'; ' prior to 28 March 2007.'; 'Nothing in this License shall be construed as excluding or limiting any implied license or other defenses to infringement that may otherwise be available to you under applicable patent law.'; '12. No Surrender of Others'' Freedom.'; 'If conditions are imposed on you (whether by court order'; ' agreement or otherwise) that contradict the conditions of this License'; ' they do not excuse you from the conditions of this License. If you cannot convey a covered work so as to satisfy simultaneously your obligations under this License and any other pertinent obligations'; ' then as a consequence you may not convey it at all. For example'; ' if you agree to terms that obligate you to collect a royalty for further conveying from those to whom you convey the Program'; ' the only way you could satisfy both those terms and this License would be to refrain entirely from conveying the Program.'; '13. Use with the GNU Affero General Public License.'; 'Notwithstanding any other provision of this License'; ' you have permission to link or combine any covered work with a work licensed under version 3 of the GNU Affero General Public License into a single combined work'; ' and to convey the resulting work. The terms of this License will continue to apply to the part which is the covered work'; ' but the special requirements of the GNU Affero General Public License'; ' section 13'; ' concerning interaction through a network will apply to the combination as such.'; '14. Revised Versions of this License.'; 'The Free Software Foundation may publish revised and/or new versions of the GNU General Public License from time to time. Such new versions will be similar in spirit to the present version'; ' but may differ in detail to address new problems or concerns.'; 'Each version is given a distinguishing version number. If the Program specifies that a certain numbered version of the GNU General Public License “or any later version” applies to it'; ' you have the option of following the terms and conditions either of that numbered version or of any later version published by the Free Software Foundation. If the Program does not specify a version number of the GNU General Public License'; ' you may choose any version ever published by the Free Software Foundation.'; 'If the Program specifies that a proxy can decide which future versions of the GNU General Public License can be used'; ' that proxy''s public statement of acceptance of a version permanently authorizes you to choose that version for the Program.'; 'Later license versions may give you additional or different permissions. However'; ' no additional obligations are imposed on any author or copyright holder as a result of your choosing to follow a later version.'; '15. Disclaimer of Warranty.'; 'THERE IS NO WARRANTY FOR THE PROGRAM'; ' TO THE EXTENT PERMITTED BY APPLICABLE LAW. EXCEPT WHEN OTHERWISE STATED IN WRITING THE COPYRIGHT HOLDERS AND/OR OTHER PARTIES PROVIDE THE PROGRAM “AS IS” WITHOUT WARRANTY OF ANY KIND'; ' EITHER EXPRESSED OR IMPLIED'; ' INCLUDING'; ' BUT NOT LIMITED TO'; ' THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE. THE ENTIRE RISK AS TO THE QUALITY AND PERFORMANCE OF THE PROGRAM IS WITH YOU. SHOULD THE PROGRAM PROVE DEFECTIVE'; ' YOU ASSUME THE COST OF ALL NECESSARY SERVICING'; ' REPAIR OR CORRECTION.'; '16. Limitation of Liability.'; 'IN NO EVENT UNLESS REQUIRED BY APPLICABLE LAW OR AGREED TO IN WRITING WILL ANY COPYRIGHT HOLDER'; ' OR ANY OTHER PARTY WHO MODIFIES AND/OR CONVEYS THE PROGRAM AS PERMITTED ABOVE'; ' BE LIABLE TO YOU FOR DAMAGES'; ' INCLUDING ANY GENERAL'; ' SPECIAL'; ' INCIDENTAL OR CONSEQUENTIAL DAMAGES ARISING OUT OF THE USE OR INABILITY TO USE THE PROGRAM (INCLUDING BUT NOT LIMITED TO LOSS OF DATA OR DATA BEING RENDERED INACCURATE OR LOSSES SUSTAINED BY YOU OR THIRD PARTIES OR A FAILURE OF THE PROGRAM TO OPERATE WITH ANY OTHER PROGRAMS)'; ' EVEN IF SUCH HOLDER OR OTHER PARTY HAS BEEN ADVISED OF THE POSSIBILITY OF SUCH DAMAGES.'; '17. Interpretation of Sections 15 and 16.'; 'If the disclaimer of warranty and limitation of liability provided above cannot be given local legal effect according to their terms'; ' reviewing courts shall apply local law that most closely approximates an absolute waiver of all civil liability in connection with the Program'; ' unless a warranty or assumption of liability accompanies a copy of the Program in return for a fee.'; 'END OF TERMS AND CONDITIONS'; 'How to Apply These Terms to Your New Programs'; 'If you develop a new program'; ' and you want it to be of the greatest possible use to the public'; ' the best way to achieve this is to make it free software which everyone can redistribute and change under these terms.'; 'To do so'; ' attach the following notices to the program. It is safest to attach them to the start of each source file to most effectively state the exclusion of warranty'; ' and each file should have at least the “copyright” line and a pointer to where the full notice is found.'; '    <one line to give the program''s name and a brief idea of what it does.>'; '    Copyright (C) <year>  <name of author>'; ''; '    This program is free software: you can redistribute it and/or modify'; '    it under the terms of the GNU General Public License as published by'; '    the Free Software Foundation'; ' either version 3 of the License'; ' or'; '    (at your option) any later version.'; ''; '    This program is distributed in the hope that it will be useful'; ''; '    but WITHOUT ANY WARRANTY'; ' without even the implied warranty of'; '    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; '    GNU General Public License for more details.'; ''; '    You should have received a copy of the GNU General Public License'; '    along with this program.  If not'; ' see <https://www.gnu.org/licenses/>.'; 'Also add information on how to contact you by electronic and paper mail.'; 'If the program does terminal interaction'; ' make it output a short notice like this when it starts in an interactive mode:'; '    <program>  Copyright (C) <year>  <name of author>'; '    This program comes with ABSOLUTELY NO WARRANTY'; ' for details type `show w''.'; '    This is free software'; ' and you are welcome to redistribute it'; '    under certain conditions'; ' type `show c'' for details.'; 'The hypothetical commands `show w'' and `show c'' should show the appropriate parts of the General Public License. Of course'; ' your program''s commands might be different'; ' for a GUI interface'; ' you would use an “about box”.'; 'You should also get your employer (if you work as a programmer) or school'; ' if any'; ' to sign a “copyright disclaimer” for the program'; ' if necessary. For more information on this'; ' and how to apply and follow the GNU GPL'; ' see <https://www.gnu.org/licenses/>.'; 'The GNU General Public License does not permit incorporating your program into proprietary programs. If your program is a subroutine library'; ' you may consider it more useful to permit linking proprietary applications with the library. If this is what you want to do'; ' use the GNU Lesser General Public License instead of this License. But first'; ' please read <https://www.gnu.org/licenses/why-not-lgpl.html>.'};
            app.Panel = uipanel(app.GridLayout);
            app.Log = uieditfield(app.Panel, 'text');
            app.Lamp = uilamp(app.Panel);
            app.btnRefreshTable = uibutton(app.Panel, 'push');
            app.btnRefreshTable.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshTableButtonPushed, true);
            app.btnRefreshTable.Icon = '48px-Refresh.png';
            app.btnRefreshTable.Tooltip = {'Refresh tables and clear selections'};
            app.btnRefreshTable.Text = '';
            app.tblTraining = uitable(app.GridLayout);
            app.tblTraining.ColumnName = {'User'; 'Images Folder'; 'Task'; 'Last edit'; 'Analyzed'; 'Unique ID'};
            app.tblTraining.RowName = {};
            app.tblTraining.ColumnSortable = false;
            app.tblTraining.CellSelectionCallback = createCallbackFcn(app, @tblTrainingCellSelection, true);
            app.tblNeuralNet = uitable(app.GridLayout);
            app.tblNeuralNet.ColumnName = {'Net Name'; 'Type'; 'Model'; 'Last change'; 'Trained'; 'Unique ID'};
            app.tblNeuralNet.RowName = {};
            app.tblNeuralNet.ColumnSortable = false;
            app.tblNeuralNet.CellSelectionCallback = createCallbackFcn(app, @tblNeuralNetCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FollicleFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end